classdef scGEAscatter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        WelcomeUIFigure    matlab.ui.Figure
        StartButton        matlab.ui.control.Button
        v2182021Label      matlab.ui.control.Label
        SinglecellGeneExpressionAnalysisLabel  matlab.ui.control.Label
        scGEAscatterLabel  matlab.ui.control.Label
        UIAxes             matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.WelcomeUIFigure.Visible = 'off';
            movegui(app.WelcomeUIFigure,"center");
            pw1=fileparts(mfilename('fullpath'));
            a=imread(fullfile(pw1,'resources','waiting.png'));
            app.UIAxes.XLabel.String='';
            app.UIAxes.YLabel.String='';
            app.UIAxes.Title.String='';
            % app.UIAxes.Visible=false;
            app.UIAxes.XAxis.TickLabels = {};
            app.UIAxes.YAxis.TickLabels = {};
            I=imshow(a, 'parent', app.UIAxes,...
                'XData', [1 app.UIAxes.Position(3)], ...
                'YData', [1 app.UIAxes.Position(4)]);   
            % Set limits of axes
            app.UIAxes.XLim = [0 I.XData(2)];
            app.UIAxes.YLim = [0 I.YData(2)]; 
            app.WelcomeUIFigure.Visible = 'on';
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            delete(app)
            sc_scatter;
        end

        % Close request function: WelcomeUIFigure
        function CloseRequestFcn(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.WelcomeUIFigure = uifigure('Visible', 'off');
            app.WelcomeUIFigure.Name = 'Welcome';
            app.WelcomeUIFigure.CloseRequestFcn = createCallbackFcn(app, @CloseRequestFcn, true);
            app.UIAxes = uiaxes(app.WelcomeUIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.scGEAscatterLabel = uilabel(app.WelcomeUIFigure);
            app.scGEAscatterLabel.Text = 'scGEAscatter';
            app.SinglecellGeneExpressionAnalysisLabel = uilabel(app.WelcomeUIFigure);
            app.SinglecellGeneExpressionAnalysisLabel.Text = 'Single-cell Gene Expression Analysis';
            app.v2182021Label = uilabel(app.WelcomeUIFigure);
            app.v2182021Label.Text = 'v21.8 (2021)';
            app.StartButton = uibutton(app.WelcomeUIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start...';
            % Show the figure after all components are created
            app.WelcomeUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = scGEAscatter

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.WelcomeUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.WelcomeUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.WelcomeUIFigure)
        end
    end
end