classdef scgeatoolApp < matlab.apps.AppBase
    %SCGEATOOLAPP App of scgeatool
    %   Single-cell Gene Expression Analysis Tool App

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        ImportDataMenu                  matlab.ui.container.Menu
        ExportSaveDataMenu              matlab.ui.container.Menu
        CloseMenu                       matlab.ui.container.Menu
        EditMenu                        matlab.ui.container.Menu
        FilterGenesCellsMenu            matlab.ui.container.Menu
        AddBrushedCellstoaNewGroupMenu  matlab.ui.container.Menu
        MergeBrushedCellstoSameGroupMenu  matlab.ui.container.Menu
        RenameCellTypeorBatchIDMenu     matlab.ui.container.Menu
        MergeSCEDataVariablesinWorkspaceMenu  matlab.ui.container.Menu
        MergeSCEDataFilesMenu           matlab.ui.container.Menu
        AddEditCellAttributesMenu       matlab.ui.container.Menu
        ExportCellAttributeTableMenu    matlab.ui.container.Menu
        ExtractCellsbyMarkerExpressionMenu  matlab.ui.container.Menu
        MergeSubclustersofSameCellTypeMenu  matlab.ui.container.Menu
        SelectGenestoWorkonMenu         matlab.ui.container.Menu
        SelectHighlyVariableGenesHVGstoWorkonMenu  matlab.ui.container.Menu
        SelectLigandReceptorGenestoWorkonMenu  matlab.ui.container.Menu
        Subsample50CellstoWorkonMenu    matlab.ui.container.Menu
        DeleteBrushedCellsMenu          matlab.ui.container.Menu
        DeleteUnbrushedCellsMenu        matlab.ui.container.Menu
        SelectCellsbyClassOpeninNewWindowMenu  matlab.ui.container.Menu
        ViewMenu                        matlab.ui.container.Menu
        EmbedCellsUsingtSNEUMPPHATEMenu  matlab.ui.container.Menu
        SwitchBetween2D3DEmbeddingsMenu  matlab.ui.container.Menu
        ClusterCellsUsingCellEmbeddingSMenu  matlab.ui.container.Menu
        ClusterCellsUsingExpressionMatrixXMenu  matlab.ui.container.Menu
        GeneExpressionMenu              matlab.ui.container.Menu
        ShowCellStatesMenu              matlab.ui.container.Menu
        LabelCellGroupsMenu             matlab.ui.container.Menu
        HighlightCellGroupsMenu         matlab.ui.container.Menu
        MultiGroupingViewMenu           matlab.ui.container.Menu
        CrossTabulationMenu             matlab.ui.container.Menu
        ViewMetadataMenu                matlab.ui.container.Menu
        HemoglobinHgbGenesExpressionMenu  matlab.ui.container.Menu
        MitochondrialMtGenesExpressionMenu  matlab.ui.container.Menu
        CellQCMetricsinViolinPlotsMenu  matlab.ui.container.Menu
        ShowCellClustersGroupsIndividuallyMenu  matlab.ui.container.Menu
        CloseAllOtherFiguresMenu        matlab.ui.container.Menu
        RefreshCurrentViewMenu          matlab.ui.container.Menu
        PlotsMenu                       matlab.ui.container.Menu
        GeneExpressionDotPlotMenu       matlab.ui.container.Menu
        GeneExpressionHeatmapMenu       matlab.ui.container.Menu
        GeneExpressionCellStateStemPlotMenu  matlab.ui.container.Menu
        GeneExpressionCellStateViolinPlotMenu  matlab.ui.container.Menu
        CorrelationPlotMenu             matlab.ui.container.Menu
        SidebySideGeneExpressionMenu    matlab.ui.container.Menu
        EnrichrResultTabletoCircosPlotMenu  matlab.ui.container.Menu
        CellStateRadarPlotMenu          matlab.ui.container.Menu
        CellkNNNetworkMenu              matlab.ui.container.Menu
        CellTrajectoryMenu              matlab.ui.container.Menu
        NextMarkerTypeMenu              matlab.ui.container.Menu
        NextColormapMenu                matlab.ui.container.Menu
        CleantSNEUMAPPHATEPlotMenu      matlab.ui.container.Menu
        AnnotateMenu                    matlab.ui.container.Menu
        AnnotateCellTypesUsingPanglaoDBMarkerGenesMenu  matlab.ui.container.Menu
        AnnotateCellTypesUsingCustomizedMarkerGenesMenu  matlab.ui.container.Menu
        ImportCellAnnotationfromSCEinWorkspaceMenu  matlab.ui.container.Menu
        ImportCellAnnotationfromSCEDataFileMenu  matlab.ui.container.Menu
        AnnotateCellTypesforBrushedCellsMenu  matlab.ui.container.Menu
        FindMarkerGenesforBrushedCellsMenu  matlab.ui.container.Menu
        MakeMarkerGeneHeatmapMenu       matlab.ui.container.Menu
        EstimateCellCyclePhaseMenu      matlab.ui.container.Menu
        EstimateDifferentiationPotencyMenu  matlab.ui.container.Menu
        EstimateStemnessMenu            matlab.ui.container.Menu
        EstimateDissociationGeneRatioMenu  matlab.ui.container.Menu
        SingleClickSolutionfromRawDatatoAnnotationMenu  matlab.ui.container.Menu
        AnalyzeMenu                     matlab.ui.container.Menu
        GeneExpressionStatisticsMenu    matlab.ui.container.Menu
        GeneVariabilityHVGFunctionAnalysisMenu  matlab.ui.container.Menu
        GeneProgramCellScoreAnalysisMenu  matlab.ui.container.Menu
        DifferentialExpressionDEAnalysisMenu  matlab.ui.container.Menu
        DifferentialVariabilityDVAnalysisMenu  matlab.ui.container.Menu
        DifferentialProgramDPAnalysisMenu  matlab.ui.container.Menu
        DEAnalysisinCellTypeBatchModeMenu  matlab.ui.container.Menu
        DVAnalysisinCellTypeBatchModeMenu  matlab.ui.container.Menu
        DPAnalysisinCellTypeBatchModeMenu  matlab.ui.container.Menu
        AllDEDVDPAnalysisinCellTypeBatchModeMenu  matlab.ui.container.Menu
        LASSOFeatureSelectionMenu       matlab.ui.container.Menu
        QUBOFeatureSelectionMenu        matlab.ui.container.Menu
        NetworkMenu                     matlab.ui.container.Menu
        RemoveLessInformativeGenestoReduceGeneSpaceMenu  matlab.ui.container.Menu
        PlotGRNfromEdgeGenePairListMenu  matlab.ui.container.Menu
        BuildGRNwithSelectedGenesMenu   matlab.ui.container.Menu
        BuildCompareGRNsMenu            matlab.ui.container.Menu
        ConstructGRNwithAllGenesscTenifoldNetPMID33336197Menu  matlab.ui.container.Menu
        ConstructCompareGRNsscTenifoldNetPMID33336197Menu  matlab.ui.container.Menu
        VirtualGeneKnockoutscTenifoldKnkPMID35510185Menu  matlab.ui.container.Menu
        VirtualGeneKnockoutGenKIPMID37246643Menu  matlab.ui.container.Menu
        OneSampleCellCellCommunicationscTenifoldXctPMID36787742Menu  matlab.ui.container.Menu
        TwoSampleCellCellCommunicationscTenifoldXct2PMID36787742Menu  matlab.ui.container.Menu
        VirtualCellCellCommunicationKnockoutscTenifoldCkoExperimentalMenu  matlab.ui.container.Menu
        ExternalMenu                    matlab.ui.container.Menu
        SetRandomSeedMenu               matlab.ui.container.Menu
        SetWorkingFolderMenu            matlab.ui.container.Menu
        EnrichrAnalysisMenu             matlab.ui.container.Menu
        SetUpLargeLanguageModelMenu     matlab.ui.container.Menu
        SetUpScimilarityModelMenu       matlab.ui.container.Menu
        SetUpQuantumComputingSupportPackageMenu  matlab.ui.container.Menu
        SetUpREnvironmentMenu           matlab.ui.container.Menu
        SetUpPythonEnvironmentMenu      matlab.ui.container.Menu
        UMAPtSNEEmbeddingUsingSeuratPMID25867923Menu  matlab.ui.container.Menu
        SCTransformv2RegularizationUsingSeuratPMID35042561Menu  matlab.ui.container.Menu
        PseudotimeAnalysisMonocle3PMID28825705Menu  matlab.ui.container.Menu
        AneuploidDiploidAnalysiscopykatPMID33462507Menu  matlab.ui.container.Menu
        VariationalAneuploidyAnalysisSCEVANPMID36841879Menu  matlab.ui.container.Menu
        DetectAmbientRNAContaminationDecontXPMID32138770Menu  matlab.ui.container.Menu
        MementoDEDVAnalysisPMID39454576Menu  matlab.ui.container.Menu
        DetectDoubletsScrubletPMID30954476Menu  matlab.ui.container.Menu
        BatchIntegrationHarmonyPMID31740819Menu  matlab.ui.container.Menu
        MELDPerturbationScoreMELDPMID33558698Menu  matlab.ui.container.Menu
        AnnotateCellTypesScimilarityPMID39566551Menu  matlab.ui.container.Menu
        AnnotateCellTypesPanHumanAzimuthsatijalaborgMenu  matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        OnlineDocumentationMenu         matlab.ui.container.Menu
        ShortcutsUserGuideMenu          matlab.ui.container.Menu
        ViewscGEAToolboxonFileExchangeMenu  matlab.ui.container.Menu
        CitescGEAToolboxPaperMenu       matlab.ui.container.Menu
        PapersCitingscGEAToolboxMenu    matlab.ui.container.Menu
        VisitSCGEATOOLStandaloneWebsiteMenu  matlab.ui.container.Menu
        RunSCGEATOOLinMATLABOnlineMenu  matlab.ui.container.Menu
        InstallscgeatoolAppMenu         matlab.ui.container.Menu
        CheckforUpdatesMenu             matlab.ui.container.Menu
        AboutSCGEATOOLMenu              matlab.ui.container.Menu
        Toolbar                         matlab.ui.container.Toolbar
        PushTool_ImportData             matlab.ui.container.toolbar.PushTool
        PushTool_MultigroupingView      matlab.ui.container.toolbar.PushTool
        PushTool_CrossTabulation        matlab.ui.container.toolbar.PushTool
        PushTool35                      matlab.ui.container.toolbar.PushTool
        PushTool_GeneDotPlot            matlab.ui.container.toolbar.PushTool
        PushTool_GeneHeatmap            matlab.ui.container.toolbar.PushTool
        PushTool_ScatterStemPlot        matlab.ui.container.toolbar.PushTool
        PushTool19                      matlab.ui.container.toolbar.PushTool
        PushTool20                      matlab.ui.container.toolbar.PushTool
        PushTool_CompareCellScoreBtwCls  matlab.ui.container.toolbar.PushTool
        PushTool_GetCellSignatureMatrix  matlab.ui.container.toolbar.PushTool
        PushTool_DEGene2Groups          matlab.ui.container.toolbar.PushTool
        PushTool_DVGene2Groups          matlab.ui.container.toolbar.PushTool
        PushTool_DPGene2Groups          matlab.ui.container.toolbar.PushTool
        PushTool_BuildGeneNetwork       matlab.ui.container.toolbar.PushTool
        PushTool_CompareGeneNetwork     matlab.ui.container.toolbar.PushTool
        PushTool_SaveFigToPowerPoint    matlab.ui.container.toolbar.PushTool
        PushTool_MakeVideoSnapshot      matlab.ui.container.toolbar.PushTool
        ToggleTool_ShowColorbar         matlab.ui.container.toolbar.ToggleTool
        PushTool_ResizePlotWindow       matlab.ui.container.toolbar.PushTool
        Toolbar2                        matlab.ui.container.Toolbar
        PushTool_ShowGeneExpr           matlab.ui.container.toolbar.PushTool
        PushTool_ShowCellStates         matlab.ui.container.toolbar.PushTool
        PushTool_FilterGenesCells       matlab.ui.container.toolbar.PushTool
        PushTool_LabelCellGroups        matlab.ui.container.toolbar.PushTool
        PushTool_AddBrushedToNewGroup   matlab.ui.container.toolbar.PushTool
        PushTool_MergeBrushedToSameGroup  matlab.ui.container.toolbar.PushTool
        PushTool_RenameCellTypeBatchID  matlab.ui.container.toolbar.PushTool
        PushTool_SingleClickAnnotation  matlab.ui.container.toolbar.PushTool
        PushTool_ClusterCellsS          matlab.ui.container.toolbar.PushTool
        PushTool_DetermineCellTypeCluster  matlab.ui.container.toolbar.PushTool
        PushTool_Brush4Celltypes        matlab.ui.container.toolbar.PushTool
        PushTool_Brush4Markers          matlab.ui.container.toolbar.PushTool
        PushTool_FindAllMarkers         matlab.ui.container.toolbar.PushTool
        PushTool_ShowClustersPop        matlab.ui.container.toolbar.PushTool
        PushTool_SelectCellsByClass     matlab.ui.container.toolbar.PushTool
        PushTool_DeleteSelectedCells    matlab.ui.container.toolbar.PushTool
        PushTool_SaveX                  matlab.ui.container.toolbar.PushTool
        PushTool_EmbedCells             matlab.ui.container.toolbar.PushTool
        PushTool_Switch2D3DEmbeddings   matlab.ui.container.toolbar.PushTool
        PushTool_PickMarkerType         matlab.ui.container.toolbar.PushTool
        PushTool_PIckColormap           matlab.ui.container.toolbar.PushTool
        PushTool_Refresh                matlab.ui.container.toolbar.PushTool
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)

    end
    
    properties (Access = public)
        sce = SingleCellExperiment
        h % Description
        c % Description
        cL        
    end
    
    methods (Access = private)
        
        function in_UpdateMainPlot(app)
            in_UpdatePushToolMenu(app);
            if app.sce.NumCells == 0
                app.UIAxes.Visible = "off";
            else
                app.h = gui.i_gscatter3(app.sce.s, app.sce.c, 1, [], app.UIAxes);
                title(app.UIAxes, app.sce.title);
                subtitle(app.UIAxes, '[genes x cells]');
                app.UIAxes.Visible = "on";
            end
        end
        
        function in_UpdatePushToolMenu(app)
            pushTools = i_GetConditionOnPushTool(app);
            menuItems = i_GetConditionOnMenu(app);
            tag = app.sce.NumCells ~= 0;
            set(pushTools, 'Enable', tag);
            set(menuItems, 'Enable', tag);
        end
        
        function results = i_GetAlwaysOnPushTool(app)
            results = [app.PushTool_ImportData];
        end
        
        function results = i_GetConditionOnPushTool(app)
            pushTools1 = i_GetAlwaysOnPushTool(app);
            pushTools2 = findall(app.UIFigure, 'Type', 'uipushtool');
            pushTools3 = findall(app.UIFigure, 'Type', 'uitoggletool');            
            results = setdiff([pushTools2; pushTools3], pushTools1);
        end
        
        function results = i_GetAlwaysOnMenu(app)
            results = [app.FileMenu; app.EditMenu; app.ViewMenu; ...
                app.AnnotateMenu; app.AnalyzeMenu; app.PlotsMenu; ...
                app.NetworkMenu; app.ExternalMenu; ...
                app.HelpMenu; app.SetWorkingFolderMenu; ...
                app.ImportDataMenu; app.CloseMenu; ...
                app.EnrichrAnalysisMenu; ...
                app.ViewscGEAToolboxonFileExchangeMenu; ...
                app.SetUpLargeLanguageModelMenu; ...
                app.AboutSCGEATOOLMenu];
        end
        
        function results = i_GetConditionOnMenu(app)
            menuItems1 = i_GetAlwaysOnMenu(app);
            menuItems2 = findall(app.UIFigure, 'Type', 'uimenu');
            results = setdiff(menuItems2, menuItems1);            
        end
        
        function isdone = ix_labelclusters(app, notasking)
            if nargin < 1, notasking = true; end
            isdone = false;
            if ~isempty(app.cL)
                if notasking
                    stxtyes = app.cL(app.c);
                else
                    [~, cLx] = grp2idx(app.c);
                    if isequal(app.cL, cLx)
                        stxtyes = app.c;
                    else
                        stxtyes = app.cL(app.c);
                    end
                end
                dtp = findobj(app.h, 'Type', 'datatip');
                delete(dtp);
                if isstring(stxtyes) || iscellstr(stxtyes)
                    stxtyes = strrep(stxtyes, "_", "\_");
                    stxtyes = strtrim(stxtyes);
                end
                row = dataTipTextRow('', stxtyes);
                app.h.DataTipTemplate.DataTipRows = row;
                % h.DataTipTemplate.FontSize = 5;
                for ik = 1:max(app.c)
                    idx = find(app.c == ik);
                    siv = app.sce.s(idx, :);
                    si = median(siv, 1);
                    % si = geometric_median(siv');
                    [kb] = dsearchn(siv, si);
                    % [~, k] = medoid(siv);  geometric_median
                    datatip(app.h, 'DataIndex', idx(kb));
                end
                % ptlabelclusters.State = 'on';
                isdone = true;
            end            
        end
        
        function in_EmbeddingAgain(app, ndim)
            readytoshow = false;
            if nargin < 2, ndim = []; end
            if isempty(app.sce.struct_cell_embeddings)
                app.sce.struct_cell_embeddings = pkg.e_makeembedstruct;
            end
            %if ~isfield(app.sce.struct_cell_embeddings, 'metaviz3d')
            %    app.sce.struct_cell_embeddings.('metaviz3d') = [];
            %end

            [vslist] = gui.i_checkexistingembed(app.sce, ndim);
            if ~isempty(vslist)
                answer = gui.myQuestdlg(app.UIFigure, 'Using exsiting embedding?');
            else
                answer = 'No';
            end
            if strcmp(answer, 'Yes')
                [sx] = gui.i_pickembedvalues(app.sce,[],app.UIFigure);
                if ~isempty(sx), app.sce.s = sx; end
            elseif strcmp(answer, 'No')
                [methodtag] = gui.i_pickembedmethod(app.UIFigure, true);
                if isempty(methodtag), return; end
                %if isempty(ndim), [ndim] = gui.i_choose2d3dnmore; end
                %if isempty(ndim), return; end
                %methoddimtag = sprintf('%s%dd',methodtag, ndim); 
    
                switch gui.myQuestdlg(app.UIFigure, 'Overwritten existing embeddings, if any?')
                    case 'Yes'
                        overwrittenold = true;
                    case 'No'
                        overwrittenold = false;
                    otherwise
                        return;
                end
                
                [K, usehvgs] = gui.i_gethvgnum(app.sce, app.UIFigure);
                if isempty(K), return; end                
                fw = gui.myWaitbar(app.UIFigure);
                try
                    forced = true;
                    %if contains(methoddimtag, 'tsne'), disp('tSNE perplexity = 30'); end
                    for k=1:length(methodtag)
                        if ~overwrittenold && ...
                            isfield(app.sce.struct_cell_embeddings, methodtag{k}) && ...
                            ~isempty(app.sce.struct_cell_embeddings.(methodtag{k}))
                            fprintf('Embedding cells using %s - skipping...\n', ...
                                upper(methodtag{k}));
                            continue;
                        else
                            gui.myWaitbar(app.UIFigure, fw, false, '', ...
                                sprintf('Embedding cells using %s', ...
                                upper(methodtag{k})), ...
                                (k-1)/length(methodtag));
                        end
                        ndim = 2 + contains(methodtag{k},'3d');
                        app.sce = app.sce.embedcells(methodtag{k}, forced, ...
                            usehvgs, ndim, K, [], false);
                    end
                    % disp('Following the library-size normalization and log1p-transformation, we visualized similarity among cells by projecting them into a reduced dimensional space using t-distributed stochastic neighbor embedding (t-SNE)/uniform manifold approximation and projection (UMAP).')
                    
                catch ME
                    gui.myWaitbar(app.UIFigure, fw);
                    gui.myErrordlg(app.UIFigure, ME.message, ME.identifier);
                    % rethrow(ME)
                    return;
                end
                if length(methodtag)>1, readytoshow = true; end
                gui.myWaitbar(app.UIFigure, fw);
    
            else
                return;
            end
            in_UpdateMainPlot(app);
            if readytoshow            
                if strcmp(gui.myQuestdlg(app.UIFigure, 'Show all avaiable embeddings?'), 'Yes')
                    gui.sc_multiembeddingview(app.sce, [], app.UIFigure);
                end
            end
        end            
        
        
        function [hasbrushed, ptsSelected] = i_checkbrusheddata(app)
            ptsSelected = logical(app.h.BrushData.');
            if ~any(ptsSelected)
                hasbrushed = false;
                gui.myWarndlg(app.UIFigure, "No cells are selected.");
            else
                hasbrushed = true;
            end
        end
        
        function [iscelltype] = in_pickcelltypeclusterid(app, a)        
            switch gui.myQuestdlg(app.UIFigure, a, '', {'Cluster', 'Cell Type'}, 'Cluster')
                case 'Cluster'
                    iscelltype = false;
                case 'Cell Type'
                    iscelltype = true;
                otherwise
                    iscelltype = [];
            end
        end
        
        function in_SingleClickSolution(app)
            if ~isprop(app.sce, 'c_cell_type_tx')
                disp('The sce object does not have the property c_cell_type_tx.');
                return;
            end
            if ~all(app.sce.c_cell_type_tx == "undetermined")            
                if ~strcmp(gui.myQuestdlg(app.UIFigure, ...
                        "Your data has been embedded and annotated. " + ...
                        "Single Click Solution will re-embed and " + ...
                        "annotate cells. Current embedding and " + ...
                        "annotation will be overwritten. Continue?"), 'Yes')
                    return; 
                end
            else
                if ~gui.gui_showrefinfo('Single Click Solution', ...
                        app.UIFigure), return; end
            end
            %if isempty(speciestag)
                speciestag = gui.i_selectspecies(2, false, app.UIFigure);
            %end
            if isempty(speciestag), return; end
    
            prompt = {
                'tSNE Embedding?', ...            
                'Add UMAP Embedding?', ...            
                'Add PHATE Embedding?', ...
                'Estimate Cell Cycles?', ...
                'Estimate Differentiation Potency of Cells?'};
            dlgtitle = '';
            dims = [1, 85];        
            definput = {'Yes', 'No', 'No', 'Yes', 'No'};
    
            if gui.i_isuifig(app.UIFigure)
                answer = gui.myInputdlg(prompt, dlgtitle, definput, app.UIFigure);
            else
                answer = inputdlg(prompt, dlgtitle, dims, definput);
            end
    
            if isempty(answer)
                return;
            end
    
            fw = gui.myWaitbar(app.UIFigure);
            gui.myWaitbar(app.UIFigure, fw, false, '','Basic QC Filtering...', 1/8);
            app.sce = app.sce.qcfilter;
    
            count = 1;
            if ~(strcmpi(answer{count},'Yes') || strcmpi(answer{count},'Y'))
                gui.myErrordlg(app.UIFigure, 'tSNE Embedding has to be included.','');
                return;        
            end
    
            count = count + 1;
            if strcmpi(answer{count},'Yes') || strcmpi(answer{count},'Y')
                gui.myWaitbar(app.UIFigure, fw, false, '', 'Embeding Cells Using UMAP...', 2/8);
                app.sce = app.sce.embedcells('umap3d', true, true, 3);
            end

            count = count + 1;
            if strcmpi(answer{count},'Yes') || strcmpi(answer{count},'Y')
                gui.myWaitbar(app.UIFigure, fw, false, '', 'Embeding Cells Using PHATE...', 2/8);
                app.sce = app.sce.embedcells('phate3d', true, true, 3);
            end
            gui.myWaitbar(app.UIFigure, fw, false, '', 'Embeding Cells Using tSNE...', 2/8);
            try
                app.sce = app.sce.embedcells('tsne3d', true, true, 3);
            catch ME
                gui.myWaitbar(app.UIFigure, fw);
                gui.myErrordlg(app.UIFigure, ME.message, ME.identifier);
                return;
            end
            gui.myWaitbar(app.UIFigure, fw, false, '', 'Clustering Cells Using K-means...', 3/8);
            
            app.sce = app.sce.clustercells([], [], true);
            gui.myWaitbar(app.UIFigure, fw, false, '', 'Annotating Cell Types Using PanglaoDB...', 4/8);
            app.sce = app.sce.assigncelltype(speciestag, false);
    
            count = count + 1;
            if strcmpi(answer{count},'Yes') || strcmpi(answer{count},'Y')
                gui.myWaitbar(app.UIFigure, fw, false, '', 'Estimate Cell Cycles...', 5/8);
                app.sce = app.sce.estimatecellcycle;
            end    
            count = count + 1;
            if strcmpi(answer{count},'Yes') || strcmpi(answer{count},'Y')
                gui.myWaitbar(app.UIFigure, fw, false, '', ...
                    'Estimate Differentiation Potency of Cells...', 6/8);
                app.sce = app.sce.estimatepotency(speciestag);
            end
            gui.myWaitbar(app.UIFigure, fw, false, '', '', 7/8);
            [app.c, app.cL] = grp2idx(app.sce.c_cell_type_tx);
            app.sce.c = app.c;
            gui.myWaitbar(app.UIFigure, fw);
            in_UpdateMainPlot(app);
            ix_labelclusters(app, true);
        end
        
        function in_ClusterCellsS(app)        
            if ~strcmp(gui.myQuestdlg(app.UIFigure, 'Cluster cells using embedding S?',''), 'Yes'), return; end
            [sx] = gui.i_pickembedvalues(app.sce,[],app.UIFigure);
            if isempty(sx), return; end
    
            answer = gui.myQuestdlg(app.UIFigure, 'Which method?', 'Select Algorithm', ...
                {'K-means 🐇', 'SnnDpc [DOI:10.1016/j.ins.2018.03.031] 🐢'}, 'K-means 🐇');
            if strcmpi(answer, 'K-means 🐇')
                methodtag = "kmeans";
            elseif strcmpi(answer, 'SnnDpc [DOI:10.1016/j.ins.2018.03.031] 🐢')
                methodtag = "snndpc";
                if ~gui.gui_showrefinfo('SnnDpc [DOI:10.1016/j.ins.2018.03.031]', app.UIFigure), return; end
            else
                return;
            end
            in_reclustercells(app, methodtag, sx);
        end
        
        function in_reclustercells(app, methodtag, sx)
            if nargin<3, sx = []; end
            methodtag = lower(methodtag);
            usingold = false;
            if ~isempty(app.sce.struct_cell_clusterings.(methodtag))
                answer1 = gui.myQuestdlg(app.UIFigure, sprintf('Using existing %s clustering?', ...
                    upper(methodtag)), '', ...
                    {'Yes, use existing', 'No, re-compute', ...
                    'Cancel'}, 'Yes, use existing');
                switch answer1
                    case 'Yes, use existing'
                        app.sce.c_cluster_id = app.sce.struct_cell_clusterings.(methodtag);
                        usingold = true;
                    case 'No, re-compute'
                        usingold = false;
                    case 'Cancel'
                        return;
                end
            end
            if ~usingold
                defv = round(app.sce.NumCells/100, -1);
                defva = min([2, round(app.sce.NumCells/100, -2), round(app.sce.NumCells/20, -1)]);
                if defva == 0, defva = min([10, defv]); end
                defvb = max([round(app.sce.NumCells/20, -2), round(app.sce.NumCells/20, -1)]);
                k = gui.i_inputnumk(defv, defva, defvb);
                if isempty(k), return; end
                fw = gui.myWaitbar(app.UIFigure);
                try
                    app.sce = app.sce.clustercells(k, methodtag, true, sx);
                catch ME
                    gui.myWaitbar(app.UIFigure, fw, true);
                    gui.myErrordlg(app.UIFigure, ME.message, ME.identifier);
                    return
                end
                gui.myWaitbar(app.UIFigure, fw);
            end
            [app.c, app.cL] = grp2idx(app.sce.c_cluster_id);
            app.sce.c = app.c;
            in_UpdateMainPlot(app);
        end    
        
        function in_DetermineCellTypeClustersGeneral(app, usedefaultdb)
            if nargin < 2, usedefaultdb = true; end
            if usedefaultdb
                organtag = "all";
                databasetag = "panglaodb";
                if ~gui.gui_showrefinfo('PanglaoDB [PMID:30951143]', app.UIFigure), return; end
                speciestag = gui.i_selectspecies(2, false, app.UIFigure);
                if isempty(speciestag), return; end
            else
                [Tm, Tw] = pkg.i_markerlist2weight(app.sce, app.UIFigure);
                if isempty(Tm) || isempty(Tw)
                    return;
                end
                wvalu = Tw.Var2;
                wgene = string(Tw.Var1);
                celltypev = string(Tm.Var1);
                markergenev = string(Tm.Var2);
            end
    
            [manuallyselect, bestonly] = gui.i_annotemanner(app.UIFigure);
    
            dtp = findobj(app.h, 'Type', 'datatip');
            delete(dtp);
            cLdisp = app.cL;
            if ~manuallyselect, fw = gui.myWaitbar(app.UIFigure); end

            hold(app.UIAxes,'on');
    
            for ix = 1:max(app.c)
                if ~manuallyselect
                    gui.myWaitbar(app.UIFigure, fw, false, '', '', ix/max(app.c));
                end
                ptsSelected = app.c == ix;
    
                if usedefaultdb
                    [Tct] = pkg.local_celltypebrushed(app.sce.X, app.sce.g, ...
                        app.sce.s, ptsSelected, ...
                        speciestag, organtag, databasetag, bestonly);
                else
                    [Tct] = pkg.e_determinecelltype(app.sce, ptsSelected, wvalu, ...
                        wgene, celltypev, markergenev);
                end
    
                ctxt = Tct.C1_Cell_Type;
                if manuallyselect && length(ctxt) > 1
    
                if gui.i_isuifig(app.UIFigure)
                    [indx, tf] = gui.myListdlg(app.UIFigure, ctxt, 'Select cell type');
                else
                    [indx, tf] = listdlg('PromptString', {'Select cell type'}, ...
                        'SelectionMode', 'single', 'ListString', ctxt, 'ListSize', [220, 300]);
                end
    
                if tf ~= 1, return; end
                    ctxt = Tct.C1_Cell_Type{indx};
                else
                    ctxt = Tct.C1_Cell_Type{1};
                end    
    
    
                ctxtdisp = strrep(ctxt, '_', '\_');
                ctxtdisp = sprintf('%s_{%d}', ctxtdisp, ix);
                cLdisp{ix} = ctxtdisp;
    
                ctxt = sprintf('%s_{%d}', ctxt, ix);
                app.cL{ix} = ctxt;
    
                row = dataTipTextRow('', cLdisp(app.c));
    
                app.h.DataTipTemplate.DataTipRows = row;
              
                if size(app.sce.s, 2) >= 2
                    siv = app.sce.s(ptsSelected, :);
                    si = mean(siv, 1);
                    idx = find(ptsSelected);
                    [k] = dsearchn(siv, si); % Nearest point search
                    datatip(app.h, 'DataIndex', idx(k));
                    % text(si(:,1),si(:,2),si(:,3),sprintf('%s',ctxt),...
                    %     'fontsize',10,'FontWeight','bold','BackgroundColor','w','EdgeColor','k');
                    %     elseif size(sce.s,2)==2
                    %             si=mean(sce.s(ptsSelected,:));
                    %             text(si(:,1),si(:,2),sprintf('%s',ctxt),...
                    %                  'fontsize',10,'FontWeight','bold','BackgroundColor','w','EdgeColor','k');
                end
                drawnow;
            end
            hold(app.UIAxes,'off');

            if ~manuallyselect, gui.myWaitbar(app.UIFigure, fw); end
            app.sce.c_cell_type_tx = string(app.cL(app.c));
            nx = length(unique(app.sce.c_cell_type_tx));
            if nx > 1
                newtx = erase(app.sce.c_cell_type_tx, "_{"+digitsPattern+"}");
                if length(unique(newtx)) ~= nx                
                    if strcmp(gui.myQuestdlg(app.UIFigure, 'Merge subclusters of same cell type?'), 'Yes')
                        in_MergeSubCellTypes(app);
                    end
                end
            end
        end

        
        function in_deletecells(app, ptsSelected)
            needprogressbar = false;
            if app.sce.NumCells > 8000, needprogressbar = true; end
            if needprogressbar, fw = gui.myWaitbar(app.UIFigure); end
            app.sce = app.sce.removecells(ptsSelected);
            if needprogressbar
                gui.myWaitbar(app.UIFigure, fw);
            end
            [app.c, app.cL] = grp2idx(app.sce.c);
            in_RefreshAll(app, [], true, true);
        end

        
        function in_RefreshAll(app, ~, keepview, keepcolr)
            if nargin < 4, keepcolr = false; end
            if nargin < 3, keepview = false; end
    
            % if gui.i_isuifig(app.UIFigure)
            %     keepcolr = false;
            %     keepview = false;
            % end
    
            if keepview || keepcolr
                [para] = gui.i_getoldsettings(app, app.UIFigure);
            end
            if isempty(app.sce) || app.sce.NumCells==0
                if ~isempty(app.h) && isvalid(app.h)
                    delete(app.h);
                end
                set(app.UIAxes,'Visible','off');
                in_EnDisableMenu(app, 'off');
                return;
            end
            in_EnDisableMenu(app, 'on');
            
            if ~gui.i_isuifig(app.UIFigure)
                if isvalid(app.UIFigure) && isa(app.UIFigure, 'matlab.ui.Figure'), figure(app.UIFigure); end
            end
            
            % [c,cL]=grp2idx(sce.c);
            % was3d = ~isempty(h.ZData);        
    
            if isempty(app.c), [app.c, app.cL] = grp2idx(app.sce.c); end
            if size(app.sce.s, 2) >= 3
                if keepview, [ax, bx] = view(app.UIAxes); end
                app.h = gui.i_gscatter3(app.sce.s, app.c, 1, [], app.UIAxes);
                %h=scatter3(app.UIAxes,rand(100,1),rand(100,1),rand(100,1));
                if keepview && bx~=90
                    view(app.UIAxes, ax, bx);
                end
            else        % otherwise going to show 2D
                if keepview, [ax, bx] = view(app.UIAxes); end
                app.h = gui.i_gscatter3(app.sce.s(:, 1:2), app.c, 1, [], app.UIAxes);
                if keepview && bx==90
                    view(app.UIAxes, ax, bx);
                end
            end
            if keepview
                if isfield(para,'oldMarker')
                    app.h.Marker = para.oldMarker;
                end
                if isfield(para,'oldSizeData')
                    app.h.SizeData = para.oldSizeData;
                end
            end
            if keepcolr
                if isfield(para, 'oldColorMap')
                    colormap(app.UIAxes,para.oldColorMap);
                end
            else
                kc = numel(unique(app.sce.c));
                colormap(app.UIAxes,pkg.i_mycolorlines(kc));
            end
            title(app.UIAxes, app.sce.title);
            subtitle(app.UIAxes, '[genes x cells]');
            %app.UIAxes.Toolbar.Visible = 'on';
            %set(app.UIAxes,'Visible','on');
        end
        
        function in_EnDisableMenu(app, tag)
            pushTools = i_GetConditionOnPushTool(app);
            menuItems = i_GetConditionOnMenu(app);
            set(pushTools, 'Enable', tag);
            set(menuItems, 'Enable', tag);
        end
        
        function xui_CaptureFigVid(app, ViewZ, FileName, OptionZ)
            
            if nargin < 3
                OptionZ = struct([]);
            end
            
            % check orientation of ViewZ, should be two columns and >=2 rows
            if size(ViewZ, 2) > size(ViewZ, 1)
                ViewZ = ViewZ.';
            end
            if size(ViewZ, 2) > 2
                warning('AJennings:VidWrite', ...
                    'Views should have n rows and only 2 columns. Deleting extraneous input.');
                ViewZ = ViewZ(:, 1:2); %remove any extra columns
            end
            
            if ispc
                daObj = VideoWriter(FileName, 'MPEG-4'); %my preferred format
            else
                daObj=VideoWriter(FileName); %for default video format.
            end
            % MPEG-4 CANNOT BE USED ON UNIX MACHINES
            % set values:
            % Frame rate
            if isfield(OptionZ, 'FrameRate')
                daObj.FrameRate = OptionZ.FrameRate;
            end
            if isfield(OptionZ, 'Duration') %space out view angles
                temp_n = round(OptionZ.Duration*daObj.FrameRate); % number frames
                temp_p = (temp_n - 1) / (size(ViewZ, 1) - 1); % length of each interval
                ViewZ_new = zeros(temp_n, 2);
                for inis = 1:(size(ViewZ, 1) - 1)
                    ViewZ_new(round(temp_p*(inis - 1)+1):round(temp_p*inis+1), :) = ...
                        [linspace(ViewZ(inis, 1), ViewZ(inis+1, 1), ...
                        round(temp_p*inis)-round(temp_p*(inis - 1))+1).', ...
                        linspace(ViewZ(inis, 2), ViewZ(inis+1, 2), ...
                        round(temp_p*inis)-round(temp_p*(inis - 1))+1).'];
                end
                ViewZ = ViewZ_new;
            end
            if length(ViewZ) == 2 % only initial and final given
                ViewZ = [linspace(ViewZ(1, 1), ViewZ(end, 1)).', ...
                    linspace(ViewZ(1, 2), ViewZ(end, 2)).'];
            end
            if isfield(OptionZ, 'Periodic') && OptionZ.Periodic == true
                ViewZ = ViewZ(1:(end -1), :); %remove last sample
            end
            open(daObj);
            for kathy = 1:size(ViewZ, 1)
                view(app.UIAxes, ViewZ(kathy, :));
                drawnow;
                % writeVideo(daObj, getframe(gcf)); %use figure, since axis changes size based on view
                writeVideo(daObj, getframe(app.UIFigure)); 
            end
            close(daObj);
        end
    end    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            cdgea;
            app.UIFigure.Visible = "off";
            v1 = pkg.i_get_versionnum;
            app.UIFigure.Name = "SCGEATOOL v" + v1;
            in_UpdatePushToolMenu(app);
            app.UIAxes.Visible = "off";
            movegui(app.UIFigure, 'center');
            load("example_data\new_example_sce.mat","sce");
            app.sce = sce;
            in_UpdateMainPlot(app);
            app.UIFigure.Visible = "on";
        end

        % Callback function: ImportDataMenu, PushTool_ImportData
        function PushTool_ImportDataClicked(app, event)
            [res] = gui.sc_openscedlg([], [], app.UIFigure);
            if ~isempty(res)
                app.sce = res;                
            end
            [app.c, app.cL] = grp2idx(app.sce.c);
            app.in_UpdateMainPlot;
        end

        % Callback function: PushTool_ShowGeneExpr
        function PushTool_ShowGeneExprClicked(app, event)
            gui.callback_ShowGeneExpr(app, event);
        end

        % Callback function: ExportSaveDataMenu, PushTool_SaveX
        function PushTool_SaveXClicked(app, event)
            gui.callback_SaveX(app, event);
        end

        % Callback function: PushTool_MultigroupingView
        function PushTool_MultigroupingViewClicked(app, event)
            gui.callback_MultiGroupingView(app, event);
        end

        % Callback function: PushTool_CrossTabulation
        function PushTool_CrossTabulationClicked(app, event)
            gui.callback_CrossTabulation(app, event);
        end

        % Callback function: FilterGenesCellsMenu,
        % PushTool_FilterGenesCells
        function PushTool_FilterGenesCellsClicked(app, event)
            try
                [requirerefresh, highlightindex] = ...
                    gui.callback_SelectCellsByQC(app);
            catch ME
                gui.myErrordlg(app.UIFigure, ME.message, ME.identifier);
                return;
            end
            if requirerefresh
                [app.c, app.cL] = grp2idx(app.sce.c);                
                % in_UpdateMainPlot(app);
                in_RefreshAll(app, [], true, false);
            end
            if ~isempty(highlightindex)
                app.h.BrushData = highlightindex;
            end
            
        end

        % Callback function: LabelCellGroupsMenu, PushTool_LabelCellGroups
        function PushTool_LabelCellGroupsClicked(app, event)
            dtp = findobj(app.h, 'Type', 'datatip');
            if ~isempty(dtp) % switch from on to off
                delete(dtp);
            else
                [thisc, clabel] = gui.i_select1class(app.sce, true, ...
                    [], [], app.UIFigure);
                if isempty(thisc), return; end
                [app.c, app.cL] = grp2idx(thisc);
                app.sce.c = app.c;
                in_UpdateMainPlot(app);
                fprintf('Cells are colored by %s.\n', lower(clabel));
                if max(app.c) > 200
                    gui.myWarndlg(app.UIFigure, ...
                        'Labels are not showing. Too many categories (n>200).');
                else
                    ix_labelclusters(app, true);
                end
            end
            
        end

        % Callback function: PushTool_ShowCellStates, ShowCellStatesMenu
        function PushTool_ShowCellStatesClicked(app, event)
            [thisc, clabel, ~, newpickclabel] = gui.i_select1state(app.sce, ...
                false, false, true, false, app.UIFigure);
            if isempty(thisc), return; end
            if strcmp(clabel, 'Cell Cycle Phase') && ~all(strcmp(unique(thisc), "undetermined"))
                app.sce.c_cell_cycle_tx = thisc;
            end        
            if strcmp(clabel, 'Workspace Variable...')
                clabel = gui.i_renamec(clabel, app.sce, newpickclabel, app.UIFigure);
                app.sce.list_cell_attributes = [app.sce.list_cell_attributes, clabel];
                app.sce.list_cell_attributes = [app.sce.list_cell_attributes, thisc];
            end
    
            switch gui.i_selvariabletype(thisc, app.UIFigure)
                case 'Categorical/Discrete'
                    [app.c, app.cL] = grp2idx(thisc);
                    app.sce.c = app.c;
                    in_UpdateMainPlot(app);
                    target{1} = app.UIAxes;
                    n = max(app.c);
                    f = 0.5 * (n - 1) ./ n;
                    f = 1 + f .* (1:2:2 * n);
                    cb = colorbar(target{1}, 'Ticks', f, 'TickLabels', ...
                        strrep(cellstr(app.cL), '_', '\_'));
                    cb.Label.String = strrep(clabel, '_', '\_');
                    cbtogg = findall(app.UIFigure, 'Tag', 'figToglColorbar');
                    set(cbtogg,'State','on');                
                    row = dataTipTextRow('', strrep(cellstr(app.cL(app.c)),'_','\_'));
                    app.h.DataTipTemplate.DataTipRows = row;
                case {'Numerical/Continuous','Unknown'}
                    app.sce.c = thisc;
                    switch gui.myQuestdlg(app.UIFigure, 'Show scores in new figure?','', ...
                            {'Yes, new figure', 'No, current figure', 'Cancel'}, ...
                            'Yes, new figure')
                        case 'No, current figure'
                            in_UpdateMainPlot(app);
                            target{1} = app.UIAxes;
                            target{2} = app.h;
                            if isnumeric(thisc)
                                set(target{2}, 'CData', thisc);
                            else
                                [app.c, app.cL] = grp2idx(thisc);
                                set(target{2}, 'CData', app.c);
                            end                            
                            cb = colorbar(app.UIAxes);
                            cb.Label.String = strrep(clabel, '_', '\_');
                            cbtogg = findall(app.UIFigure, 'Tag', 'figToglColorbar');
                            set(cbtogg,'State','on');
                        case 'Yes, new figure'
                            switch gui.myQuestdlg(app.UIFigure, 'Plot scatter plot type:','', ...
                                    {'Heat','Stem'},'Heat')
                                case 'Heat'
                                    figfun = @gui.i_heatscatterfig;
                                case 'Stem'
                                    figfun = @gui.i_stemscatterfig;
                                otherwise
                                    return;
                            end
                            figfun(app.sce, thisc, [], clabel, app.UIFigure);
                            return;
                        otherwise
                            return;
                    end
                otherwise
                    return;
            end            
        end

        % Callback function: PushTool_Refresh
        function PushTool_RefreshClicked(app, event)
            in_UpdateMainPlot(app);
        end

        % Callback function: PushTool_PIckColormap
        function PushTool_PIckColormapClicked(app, event)
            gui.callback_PickColorMap(app);
        end

        % Callback function: EmbedCellsUsingtSNEUMPPHATEMenu, 
        % ...and 1 other component
        function PushTool_EmbedCellsClicked(app, event)
            in_EmbeddingAgain(app);
        end

        % Callback function: PushTool_PickMarkerType
        function PushTool_PickMarkerTypeClicked(app, event)
            if app.h.Marker == '.'
                app.h.Marker = 'o';
                app.h.SizeData = 10 * randi(10);
            else
                app.h.Marker = '.';
                app.h.SizeData = 50 * randi(10);
            end
        end

        % Callback function: PushTool_Switch2D3DEmbeddings, 
        % ...and 1 other component
        function PushTool_Switch2D3DEmbeddingsClicked(app, event)
            [para] = gui.i_getoldsettings(app);
            [ax, bx] = view(app.UIAxes); 
            if bx == 90   % isempty(h.ZData)               % current 2D            
                if ~strcmp(gui.myQuestdlg(app.UIFigure, 'Switch to 3D?',''), 'Yes'), return; end
                if isvalid(app.UIFigure) && isa(app.UIFigure, 'matlab.ui.Figure'), figure(app.UIFigure); end
                if size(app.sce.s, 2) >= 3
                    app.h = gui.i_gscatter3(app.sce.s, app.c, 1, [], app.UIAxes);
                    if ~isempty(ax) && ~isempty(bx) && ~any([ax, bx] == 0)
                        view(app.UIAxes, ax, bx);
                    else
                        view(app.UIAxes, 3);
                    end
                else
                    [vslist] = gui.i_checkexistingembed(app.sce, 3);
                    if isempty(vslist)
                        in_EmbeddingAgain(app, 3);
                    else
                        ansx = gui.myQuestdlg(app.UIFigure, 'Using existing 3D embedding? Select "No" to re-embed.');
                        if isempty(ansx), return; end
                        switch ansx
                            case 'Yes'
                                [sx] = gui.i_pickembedvalues(app.sce, 3, app.UIFigure);
                                if ~isempty(sx) && size(sx,1) == app.sce.NumCells
                                    app.sce.s = sx;
                                    view(app.UIAxes, 3);
                                else
                                    return;
                                end
                            case 'No'
                                in_EmbeddingAgain(app, 3);
                            case 'Cancel'
                                return;
                        end
                    end
                end
            else        % current 3D do following
                if ~strcmp(gui.myQuestdlg(app.UIFigure, 'Switch to 2D?',''), 'Yes'), return; end
                [vslist] = gui.i_checkexistingembed(app.sce, 2);
                
                if ~isempty(vslist)
                    answer = gui.myQuestdlg(app.UIFigure, 'How to make 2D embedding?','', ...
                        {'Pick existing 2D','Re-embed cells', ...
                        'Reduce current 3D'},'Pick existing 2D');
                else
                    answer = gui.myQuestdlg(app.UIFigure, 'How to make 2D embedding?','', ...
                        {'Embed Cells to 2D', ...
                        'Project Current 3D Embedding to 2D','Cancel'},'Embed Cells to 2D');
                end
                if isempty(answer), return; end
                switch answer
                    case 'Cancel'
                        return;
                    case 'Re-embed cells'
                         in_EmbeddingAgain(app, 2);
                    case {'Project Current 3D Embedding to 2D', 'Reduce current 3D'}
                        [ax, bx] = view(app.UIAxes);
                        answer2 = gui.myQuestdlg(app.UIFigure, 'Which view to be used to project 3D to 2D?', '', ...
                            {'X-Y Plane', 'Screen/Camera', 'PCA-Rotated'}, 'X-Y Plane');
                        switch answer2
                            case 'X-Y Plane'
                                sx = app.sce.s;
                            case 'Screen/Camera'
                                sx = pkg.i_3d2d(app.sce.s, ax, bx);
                            case {'PCA-Rotated'}
                                [~, sx] = pca(app.sce.s);
                            otherwise
                                return;
                        end
                        app.h = gui.i_gscatter3(sx(:, 1:2), app.c, ...
                            methodid, [], app.UIAxes);
                        app.sce.s = sx(:, 1:2);
                        title(app.UIAxes, app.sce.title);
                        subtitle(app.UIAxes, '[genes x cells]');
                        app.h.Marker = para.oldMarker;
                        app.h.SizeData = para.oldSizeData;
                        colormap(app.UIAxes,para.oldColorMap);
                        view(app.UIAxes, 2);
                        return;
                    case 'Pick existing 2D'
                        [sx] = gui.i_pickembedvalues(app.sce, 2, app.UIFigure);
                        if ~isempty(sx) && size(sx,1) == app.sce.NumCells
                            app.sce.s = sx;
                            view(app.UIAxes, 2);
                        else
                            return;
                        end
                end
            end
            
        end

        % Callback function: AddBrushedCellstoaNewGroupMenu, 
        % ...and 1 other component
        function PushTool_AddBrushedToNewGroupClicked(app, event)
            [hasbrushed, ptsSelected] = i_checkbrusheddata(app);
            if ~hasbrushed, return; end
            [iscelltype] = in_pickcelltypeclusterid(app, 'Make a new cluster or new cell type group out of brushed cells?');
            if isempty(iscelltype), return; end
            if iscelltype
                n = sum(contains(unique(app.sce.c_cell_type_tx), "New cell type"));
                if n > 0
                    nname = sprintf('New cell type %d', n+1);
                else
                    nname = 'New cell type';
                end
                
                if gui.i_isuifig(app.UIFigure)                
                    newctype = gui.myInputdlg({'Enter new cell type name:'}, ...
                        '', {nname}, app.UIFigure);
                else
                    newctype = inputdlg('Enter new cell type name:', ...
                        '', [1, 50], {nname});
                end
    
                if isempty(newctype), return; end
                app.sce.c_cell_type_tx(ptsSelected) = string(newctype);
                [app.c, app.cL] = grp2idx(app.sce.c_cell_type_tx);
            else
                app.c(ptsSelected) = max(app.c) + 1;
                [app.c, app.cL] = grp2idx(app.c);
                app.sce.c_cluster_id = app.c;
            end
            app.sce.c = app.c;
            [ax, bx] = view(app.UIAxes);
            [app.h] = gui.i_gscatter3(app.sce.s, app.c, 1, [], app.UIAxes);
            title(app.UIAxes, app.sce.title);
            subtitle(app.UIAxes, '[genes x cells]');
            view(app.UIAxes, [ax, bx]);
            ix_labelclusters(app, true);         
        end

        % Callback function: MergeBrushedCellstoSameGroupMenu, 
        % ...and 1 other component
        function PushTool_MergeBrushedToSameGroupClicked(app, event)
            [hasbrushed, ptsSelected] = i_checkbrusheddata(app);
            if ~hasbrushed, return; end
            [iscelltype] = in_pickcelltypeclusterid(app,'Merge brushed cells into same cluster or same cell type?');
            if isempty(iscelltype), return; end
            if iscelltype
                c_members = unique(app.sce.c_cell_type_tx(ptsSelected));
            else
                c_members = unique(app.c(ptsSelected));
            end
    
            if isscalar(c_members)
                gui.myWarndlg(app.UIFigure, ...
                "All brushed cells are in one cluster or belong to the same cell type.",'');
                return;
            end
    
            if gui.i_isuifig(app.UIFigure)
                [indx, tf] = gui.myListdlg(app.UIFigure, string(c_members), ...
                    'Select target cluster');
            else
                [indx, tf] = listdlg('PromptString', ...
                    {'Select target cluster'}, 'SelectionMode', ...
                    'single', 'ListString', string(c_members), ...
                    'ListSize', [220, 300]);
            end
            if tf == 1
                c_target = c_members(indx);
            else
                return;
            end
    
            if iscelltype
                app.sce.c_cell_type_tx(ismember(app.sce.c_cell_type_tx, c_members)) = c_target;
                [app.c, app.cL] = grp2idx(app.sce.c_cell_type_tx);
            else
                app.c(ismember(app.c, c_members)) = c_target;
                [app.c, app.cL] = grp2idx(app.c);
                app.sce.c = app.c;
                app.sce.c_cluster_id = app.c;
            end
            [ax, bx] = view(app.UIAxes);
            [app.h] = gui.i_gscatter3(app.sce.s, app.c, 1, [], app.UIAxes);
            title(app.UIAxes, app.sce.title);
            subtitle(app.UIAxes, '[genes x cells]');
            view(app.UIAxes, [ax, bx]);
            ix_labelclusters(app, true);            
        end

        % Callback function: PushTool_ResizePlotWindow
        function PushTool_ResizePlotWindowClicked(app, event)
            gui.i_resizewin([],[],app.UIFigure);
        end

        % Callback function: PushTool_SaveFigToPowerPoint
        function PushTool_SaveFigToPowerPointClicked(app, event)
            gui.i_savemainfig(app, [], 3);
        end

        % Callback function: PushTool_MakeVideoSnapshot
        function PushTool_MakeVideoSnapshotClicked(app, event)
            answer = gui.myQuestdlg(app.UIFigure, 'Make video snapshot?');
            if ~strcmp(answer, 'Yes'), return; end
    
            [caz, cel] = view(app.UIAxes);        
            OptionZ.FrameRate = 15;
            OptionZ.Duration = 5.5;
            OptionZ.Periodic = true;
            fname = tempname;
            warning off
            flatview = rand>0.5;
            if flatview
                ax = [-20, 50; -110, 65; -190, 80; -290, 60; -380, 40];
            else
                ax = [-20, 10; -110, 10; -190, 80; -290, 10; -380, 10];
            end
            try
                xui_CaptureFigVid(app, ax, fname, OptionZ);
                % xui.CaptureFigVid(ax, fname, OptionZ, app.UIAxes, app.UIFigure);
            catch
               
            end
            view(app.UIAxes,caz,cel);
            warning on
            %pause(1);
            %winopen(tempdir);
            %pause(1);
            vfile = sprintf('%s.mp4', fname);
            if exist(vfile, 'file')
                winopen(vfile);
            else
                vfile = sprintf('%s.avi', fname);
                if exist(vfile, 'file')
                    winopen(vfile);
                end
            end          
        end

        % Callback function: PushTool_RenameCellTypeBatchID
        function PushTool_RenameCellTypeBatchIDClicked(app, event)
            answer = gui.myQuestdlg(app.UIFigure, 'Rename cell type, batch ID, or gene name?', ...
                '', {'Cell type', 'Batch ID', 'Others...'}, 'Cell type');
            switch answer
                case 'Cell type'
                    [requirerefresh] = gui.callback_RenameCellType(app);
                case 'Batch ID'
                    [requirerefresh] = gui.callback_RenameBatchID(app);
                case 'Gene name'
                    [requirerefresh] = gui.callback_RenameGenes(app);
                case 'Others...'
                    [requirerefresh, answer] = gui.callback_RenameOthers(app);
                otherwise
                    return;
            end
            if requirerefresh
                switch answer
                    case 'Cell type'
                        [app.c, app.cL] = grp2idx(app.sce.c_cell_type_tx);
                    case 'Batch ID'
                        [app.c, app.cL] = grp2idx(app.sce.c_batch_id);
                    case 'Cluster ID'
                        [app.c, app.cL] = grp2idx(app.sce.c_cluster_id);
                    otherwise
                        return;
                end
                ix_labelclusters(app, true);
            end
            
        end

        % Off callback: ToggleTool_ShowColorbar
        function ToggleTool_ShowColorbarOff(app, event)
            colorbar(app.UIAxes, "off");
        end

        % On callback: ToggleTool_ShowColorbar
        function ToggleTool_ShowColorbarOn(app, event)
            colorbar(app.UIAxes);
        end

        % Menu selected function: AboutSCGEATOOLMenu
        function AboutSCGEATOOLMenuSelected(app, event)
                [~, ~, ~, im] = pkg.i_majvercheck([false false false true]);
                gui.sc_simpleabout(app.UIFigure, im);                
        end

        % Menu selected function: EnrichrAnalysisMenu
        function EnrichrAnalysisMenuSelected(app, event)
            gui.callback_RunEnrichr(app, event);
        end

        % Menu selected function: SetUpLargeLanguageModelMenu
        function SetUpLargeLanguageModelMenuSelected(app, event)
            gui.i_setllmmodel(app);
        end

        % Menu selected function: SetWorkingFolderMenu
        function SetWorkingFolderMenuSelected(app, event)
            [done] = gui.i_setwrkdir('externalwrkpath');
            if done
                 gui.myHelpdlg(app.UIFigure, "External program working " + ...
                     "directory is set successfully.");
            end
        end

        % Menu selected function: ViewscGEAToolboxonFileExchangeMenu
        function ViewscGEAToolboxonFileExchangeMenuSelected(app, event)
            web('https://www.mathworks.com/matlabcentral/fileexchange/72917-scgeatoolbox-single-cell-gene-expression-analysis-toolbox');
        end

        % Menu selected function: CloseMenu
        function CloseMenuSelected(app, event)
            app.delete;
        end

        % Callback function: PushTool_GeneDotPlot
        function PushTool_GeneDotPlotClicked(app, event)
            gui.callback_Dotplot(app);
        end

        % Callback function: PushTool_GeneHeatmap
        function PushTool_GeneHeatmapClicked(app, event)
            gui.callback_Heatmap(app);
        end

        % Callback function: PushTool_ScatterStemPlot
        function PushTool_ScatterStemPlotClicked(app, event)
            gui.callback_ScatterStemPlot(app);
        end

        % Callback function: PushTool_SingleClickAnnotation
        function PushTool_SingleClickAnnotationClicked(app, event)
            in_SingleClickSolution(app);
        end

        % Callback function: PushTool_ClusterCellsS
        function PushTool_ClusterCellsSClicked(app, event)
            in_ClusterCellsS(app);
        end

        % Callback function: PushTool_CompareCellScoreBtwCls
        function PushTool_CompareCellScoreBtwClsClicked(app, event)
            gui.callback_CompareCellScoreBtwCls(app);
        end

        % Callback function: PushTool_DetermineCellTypeCluster
        function PushTool_DetermineCellTypeClusterClicked(app, event)
            in_DetermineCellTypeClustersGeneral(app);
        end

        % Callback function: PushTool_GetCellSignatureMatrix
        function PushTool_GetCellSignatureMatrixClicked(app, event)
            gui.callback_GetCellSignatureMatrix(app);
        end

        % Callback function: PushTool_DEGene2Groups
        function PushTool_DEGene2GroupsClicked(app, event)
            gui.callback_DEGene2Groups(app)
        end

        % Callback function: PushTool_DVGene2Groups
        function PushTool_DVGene2GroupsClicked(app, event)
            gui.callback_DVGene2Groups(app);
        end

        % Callback function: PushTool_DPGene2Groups
        function PushTool_DPGene2GroupsClicked(app, event)
            gui.callback_DPGene2Groups(app);
        end

        % Callback function: PushTool_BuildGeneNetwork
        function PushTool_BuildGeneNetworkClicked(app, event)
            gui.callback_BuildGeneNetwork(app);
        end

        % Callback function: PushTool_CompareGeneNetwork
        function PushTool_CompareGeneNetworkClicked(app, event)
            gui.callback_CompareGeneNetwork(app);
        end

        % Callback function: PushTool_Brush4Celltypes
        function PushTool_Brush4CelltypesClicked(app, event)
            ptsSelected = logical(app.h.BrushData.');
            if ~any(ptsSelected)
                gui.myHelpdlg(app.UIFigure,...
                    "No cells are selected. Please use the data brush tool to " + ...
                    "select cells for cell type assignment.");
                return;
            end        
            if ~strcmp(gui.myQuestdlg(app.UIFigure, ...
                    ['This is a one-time analysis. Cell type labels will ' ...
                    'not be saved. Continue?']), 'Yes')
                return;
            end
            if ~exist('speciestag', 'var') || isempty(speciestag)
                speciestag = gui.i_selectspecies(2, false, app.UIFigure);
            end
            if isempty(speciestag), return; end
            fw = gui.myWaitbar(app.UIFigure);
            [Tct] = pkg.local_celltypebrushed(app.sce.X, app.sce.g, app.sce.s, ...
                ptsSelected, ...
                speciestag, "all", "panglaodb", false);
            ctxt = Tct.C1_Cell_Type;
            gui.myWaitbar(app.UIFigure,fw);
    
            if gui.i_isuifig(app.UIFigure)
                [indx, tf] = gui.myListdlg(app.UIFigure, ctxt, 'Select cell type');
            else        
                [indx, tf] = listdlg('PromptString', ...
                    {'Select cell type'}, 'SelectionMode', 'single', ...
                    'ListString', ctxt, 'ListSize', [220, 300]);
            end
            if tf == 1
                ctxt = Tct.C1_Cell_Type{indx};
            else
                return;
            end
            ctxt = strrep(ctxt, '_', '\_');
            delete(findall(app.UIFigure, 'Type', 'hggroup'));
            if ~exist('tmpcelltypev', 'var') || length(tmpcelltypev) ~= app.sce.NumCells
                tmpcelltypev = cell(app.sce.NumCells, 1);            
            end
            siv = app.sce.s(ptsSelected, :);
            si = mean(app.sce.s(ptsSelected, :));
            [k] = dsearchn(siv, si);
            idx = find(ptsSelected);
            tmpcelltypev{idx(k)} = ctxt;
            rowx = dataTipTextRow('', tmpcelltypev);
            app.h.DataTipTemplate.DataTipRows = rowx;
            datatip(app.h, 'DataIndex', idx(k));            
        end

        % Callback function: PushTool_Brush4Markers
        function PushTool_Brush4MarkersClicked(app, event)
            gui.callback_Brush4Markers(app);
        end

        % Callback function: PushTool_FindAllMarkers
        function PushTool_FindAllMarkersClicked(app, event)
            gui.callback_FindAllMarkers(app);
        end

        % Callback function: PushTool_ShowClustersPop
        function PushTool_ShowClustersPopClicked(app, event)
            gui.callback_ShowClustersPop(app);
        end

        % Callback function: PushTool_SelectCellsByClass
        function PushTool_SelectCellsByClassClicked(app, event)
            gui.callback_SelectCellsByClass(app);
        end

        % Callback function: PushTool_DeleteSelectedCells
        function PushTool_DeleteSelectedCellsClicked(app, event)
            ptsSelected = logical(app.h.BrushData.');
            if ~any(ptsSelected)
                gui.myWarndlg(app.UIFigure, "No cells are selected.");
                return;
            end
            [ptsSelected, letdoit] = gui.i_expandbrushed(ptsSelected, ...
                app.sce, app.UIFigure);
            if ~letdoit, return; end
    
            answer = gui.myQuestdlg(app.UIFigure, 'Delete selected or unselected cells?', '', ...
                {'Selected', 'Unselected'}, 'Selected');
            if isempty(answer), return; end
            if strcmp(answer, 'Unselected')
                in_deletecells(app, ~ptsSelected);
            elseif strcmp(answer, 'Selected')
                in_deletecells(app, ptsSelected);
            else
                return;
            end         
        end

        % Menu selected function: OnlineDocumentationMenu
        function OnlineDocumentationMenuSelected(app, event)
             web('https://scgeatoolbox.readthedocs.io/en/latest/');
        end

        % Menu selected function: ShortcutsUserGuideMenu
        function ShortcutsUserGuideMenuSelected(app, event)
            gui.gui_showrefinfo('Shortcuts Guide',app.UIFigure);
        end

        % Menu selected function: PapersCitingscGEAToolboxMenu
        function PapersCitingscGEAToolboxMenuSelected(app, event)
            web('https://scholar.google.com/scholar?cites=4661048952867744439&as_sdt=5,44&sciodt=0,44&hl=en');
        end

        % Menu selected function: VisitSCGEATOOLStandaloneWebsiteMenu
        function VisitSCGEATOOLStandaloneWebsiteMenuSelected(app, event)
            web('https://scgeatool.github.io/');
        end

        % Menu selected function: RunSCGEATOOLinMATLABOnlineMenu
        function RunSCGEATOOLinMATLABOnlineMenuSelected(app, event)
            web('https://matlab.mathworks.com/open/github/v1?repo=jamesjcai/scGEAToolbox&file=online_landing.m');
        end

        % Menu selected function: CitescGEAToolboxPaperMenu
        function CitescGEAToolboxPaperMenuSelected(app, event)
            web('https://pubmed.ncbi.nlm.nih.gov/31697351/');
        end

        % Menu selected function: PlotGRNfromEdgeGenePairListMenu
        function PlotGRNfromEdgeGenePairListMenuSelected(app, event)
            gui.callback_DrawNetwork(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'SCGEATOOL APP';
            app.UIFigure.Icon = fullfile(pathToMLAPP, 'app', 'scgeatoolApp_resources', 'icon_16.png');
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = '&File';
            app.ImportDataMenu = uimenu(app.FileMenu);
            app.ImportDataMenu.MenuSelectedFcn = createCallbackFcn(app, @PushTool_ImportDataClicked, true);
            app.ImportDataMenu.Accelerator = 'i';
            app.ImportDataMenu.Text = '&Import Data...';
            app.ExportSaveDataMenu = uimenu(app.FileMenu);
            app.ExportSaveDataMenu.MenuSelectedFcn = createCallbackFcn(app, @PushTool_SaveXClicked, true);
            app.ExportSaveDataMenu.Accelerator = 's';
            app.ExportSaveDataMenu.Text = 'Export/Save Data...';
            app.CloseMenu = uimenu(app.FileMenu);
            app.CloseMenu.MenuSelectedFcn = createCallbackFcn(app, @CloseMenuSelected, true);
            app.CloseMenu.Separator = 'on';
            app.CloseMenu.Accelerator = 'w';
            app.CloseMenu.Text = 'Close';
            app.EditMenu = uimenu(app.UIFigure);
            app.EditMenu.Text = '&Edit';
            app.FilterGenesCellsMenu = uimenu(app.EditMenu);
            app.FilterGenesCellsMenu.MenuSelectedFcn = createCallbackFcn(app, @PushTool_FilterGenesCellsClicked, true);
            app.FilterGenesCellsMenu.Accelerator = 'f';
            app.FilterGenesCellsMenu.Text = '&Filter Genes && Cells...';
            app.AddBrushedCellstoaNewGroupMenu = uimenu(app.EditMenu);
            app.AddBrushedCellstoaNewGroupMenu.MenuSelectedFcn = createCallbackFcn(app, @PushTool_AddBrushedToNewGroupClicked, true);
            app.AddBrushedCellstoaNewGroupMenu.Separator = 'on';
            app.AddBrushedCellstoaNewGroupMenu.Text = 'Add Brushed Cells to a New Group';
            app.MergeBrushedCellstoSameGroupMenu = uimenu(app.EditMenu);
            app.MergeBrushedCellstoSameGroupMenu.MenuSelectedFcn = createCallbackFcn(app, @PushTool_MergeBrushedToSameGroupClicked, true);
            app.MergeBrushedCellstoSameGroupMenu.Text = 'Merge Brushed Cells to Same Group';
            app.RenameCellTypeorBatchIDMenu = uimenu(app.EditMenu);
            app.RenameCellTypeorBatchIDMenu.Text = 'Rename Cell Type or Batch ID...';
            app.MergeSCEDataVariablesinWorkspaceMenu = uimenu(app.EditMenu);
            app.MergeSCEDataVariablesinWorkspaceMenu.Separator = 'on';
            app.MergeSCEDataVariablesinWorkspaceMenu.Text = 'Merge SCE Data Variables in Workspace...';
            app.MergeSCEDataFilesMenu = uimenu(app.EditMenu);
            app.MergeSCEDataFilesMenu.Text = 'Merge SCE Data Files...';
            app.AddEditCellAttributesMenu = uimenu(app.EditMenu);
            app.AddEditCellAttributesMenu.Separator = 'on';
            app.AddEditCellAttributesMenu.Text = 'Add/Edit Cell Attributes...';
            app.ExportCellAttributeTableMenu = uimenu(app.EditMenu);
            app.ExportCellAttributeTableMenu.Text = 'Export Cell Attribute Table...';
            app.ExtractCellsbyMarkerExpressionMenu = uimenu(app.EditMenu);
            app.ExtractCellsbyMarkerExpressionMenu.Separator = 'on';
            app.ExtractCellsbyMarkerExpressionMenu.Text = 'Extract Cells by Marker (+/-) Expression...';
            app.MergeSubclustersofSameCellTypeMenu = uimenu(app.EditMenu);
            app.MergeSubclustersofSameCellTypeMenu.Text = 'Merge Subclusters of Same Cell Type';
            app.SelectGenestoWorkonMenu = uimenu(app.EditMenu);
            app.SelectGenestoWorkonMenu.Separator = 'on';
            app.SelectGenestoWorkonMenu.Text = 'Select Genes to Work on...';
            app.SelectHighlyVariableGenesHVGstoWorkonMenu = uimenu(app.EditMenu);
            app.SelectHighlyVariableGenesHVGstoWorkonMenu.Text = 'Select Highly Variable Genes (HVGs) to Work on...';
            app.SelectLigandReceptorGenestoWorkonMenu = uimenu(app.EditMenu);
            app.SelectLigandReceptorGenestoWorkonMenu.Text = 'Select Ligand Receptor Genes to Work on...';
            app.Subsample50CellstoWorkonMenu = uimenu(app.EditMenu);
            app.Subsample50CellstoWorkonMenu.Text = 'Subsample 50% Cells to Work on...';
            app.DeleteBrushedCellsMenu = uimenu(app.EditMenu);
            app.DeleteBrushedCellsMenu.Separator = 'on';
            app.DeleteBrushedCellsMenu.Text = 'Delete Brushed Cells...';
            app.DeleteUnbrushedCellsMenu = uimenu(app.EditMenu);
            app.DeleteUnbrushedCellsMenu.Text = 'Delete Unbrushed Cells...';
            app.SelectCellsbyClassOpeninNewWindowMenu = uimenu(app.EditMenu);
            app.SelectCellsbyClassOpeninNewWindowMenu.Separator = 'on';
            app.SelectCellsbyClassOpeninNewWindowMenu.Text = 'Select Cells by Class & Open in New Window...';
            app.ViewMenu = uimenu(app.UIFigure);
            app.ViewMenu.Text = 'View';
            app.EmbedCellsUsingtSNEUMPPHATEMenu = uimenu(app.ViewMenu);
            app.EmbedCellsUsingtSNEUMPPHATEMenu.MenuSelectedFcn = createCallbackFcn(app, @PushTool_EmbedCellsClicked, true);
            app.EmbedCellsUsingtSNEUMPPHATEMenu.Accelerator = 'b';
            app.EmbedCellsUsingtSNEUMPPHATEMenu.Text = 'Embed Cells Using tSNE, UMP, PHATE...';
            app.SwitchBetween2D3DEmbeddingsMenu = uimenu(app.ViewMenu);
            app.SwitchBetween2D3DEmbeddingsMenu.MenuSelectedFcn = createCallbackFcn(app, @PushTool_Switch2D3DEmbeddingsClicked, true);
            app.SwitchBetween2D3DEmbeddingsMenu.Text = 'Switch Between 2D/3D Embeddings...';
            app.ClusterCellsUsingCellEmbeddingSMenu = uimenu(app.ViewMenu);
            app.ClusterCellsUsingCellEmbeddingSMenu.Separator = 'on';
            app.ClusterCellsUsingCellEmbeddingSMenu.Accelerator = 'c';
            app.ClusterCellsUsingCellEmbeddingSMenu.Text = 'Cluster Cells Using Cell Embedding (S)';
            app.ClusterCellsUsingExpressionMatrixXMenu = uimenu(app.ViewMenu);
            app.ClusterCellsUsingExpressionMatrixXMenu.Text = 'Cluster Cells Using Expression Matrix (X)...';
            app.GeneExpressionMenu = uimenu(app.ViewMenu);
            app.GeneExpressionMenu.Separator = 'on';
            app.GeneExpressionMenu.Accelerator = 'E';
            app.GeneExpressionMenu.Text = 'Gene Expression...';
            app.ShowCellStatesMenu = uimenu(app.ViewMenu);
            app.ShowCellStatesMenu.MenuSelectedFcn = createCallbackFcn(app, @PushTool_ShowCellStatesClicked, true);
            app.ShowCellStatesMenu.Accelerator = 'T';
            app.ShowCellStatesMenu.Text = 'Show Cell States...';
            app.LabelCellGroupsMenu = uimenu(app.ViewMenu);
            app.LabelCellGroupsMenu.MenuSelectedFcn = createCallbackFcn(app, @PushTool_LabelCellGroupsClicked, true);
            app.LabelCellGroupsMenu.Accelerator = 'G';
            app.LabelCellGroupsMenu.Text = 'Label Cell Groups...';
            app.HighlightCellGroupsMenu = uimenu(app.ViewMenu);
            app.HighlightCellGroupsMenu.Text = 'Highlight Cell Groups...';
            app.MultiGroupingViewMenu = uimenu(app.ViewMenu);
            app.MultiGroupingViewMenu.Text = 'Multi-Grouping View...';
            app.CrossTabulationMenu = uimenu(app.ViewMenu);
            app.CrossTabulationMenu.Text = 'Cross Tabulation';
            app.ViewMetadataMenu = uimenu(app.ViewMenu);
            app.ViewMetadataMenu.Separator = 'on';
            app.ViewMetadataMenu.Accelerator = 'M';
            app.ViewMetadataMenu.Text = 'View Metadata...';
            app.HemoglobinHgbGenesExpressionMenu = uimenu(app.ViewMenu);
            app.HemoglobinHgbGenesExpressionMenu.Separator = 'on';
            app.HemoglobinHgbGenesExpressionMenu.Text = 'Hemoglobin (Hgb) Genes Expression...';
            app.MitochondrialMtGenesExpressionMenu = uimenu(app.ViewMenu);
            app.MitochondrialMtGenesExpressionMenu.Text = 'Mitochondrial (Mt-) Genes Expression...';
            app.CellQCMetricsinViolinPlotsMenu = uimenu(app.ViewMenu);
            app.CellQCMetricsinViolinPlotsMenu.Text = 'Cell QC Metrics in Violin Plots...';
            app.ShowCellClustersGroupsIndividuallyMenu = uimenu(app.ViewMenu);
            app.ShowCellClustersGroupsIndividuallyMenu.Separator = 'on';
            app.ShowCellClustersGroupsIndividuallyMenu.Text = 'Show Cell Clusters/Groups Individually...';
            app.CloseAllOtherFiguresMenu = uimenu(app.ViewMenu);
            app.CloseAllOtherFiguresMenu.Separator = 'on';
            app.CloseAllOtherFiguresMenu.Accelerator = 'x';
            app.CloseAllOtherFiguresMenu.Text = 'Close All Other Figures';
            app.RefreshCurrentViewMenu = uimenu(app.ViewMenu);
            app.RefreshCurrentViewMenu.Accelerator = 'r';
            app.RefreshCurrentViewMenu.Text = 'Refresh Current View';
            app.PlotsMenu = uimenu(app.UIFigure);
            app.PlotsMenu.Text = 'Plots';
            app.GeneExpressionDotPlotMenu = uimenu(app.PlotsMenu);
            app.GeneExpressionDotPlotMenu.Text = 'Gene Expression Dot Plot...';
            app.GeneExpressionHeatmapMenu = uimenu(app.PlotsMenu);
            app.GeneExpressionHeatmapMenu.Text = 'Gene Expression Heatmap...';
            app.GeneExpressionCellStateStemPlotMenu = uimenu(app.PlotsMenu);
            app.GeneExpressionCellStateStemPlotMenu.Text = 'Gene Expression/Cell State Stem Plot...';
            app.GeneExpressionCellStateViolinPlotMenu = uimenu(app.PlotsMenu);
            app.GeneExpressionCellStateViolinPlotMenu.Text = 'Gene Expression/Cell State Violin Plot...';
            app.CorrelationPlotMenu = uimenu(app.PlotsMenu);
            app.CorrelationPlotMenu.Text = 'Correlation Plot...';
            app.SidebySideGeneExpressionMenu = uimenu(app.PlotsMenu);
            app.SidebySideGeneExpressionMenu.Separator = 'on';
            app.SidebySideGeneExpressionMenu.Text = 'Side-by-Side Gene Expression...';
            app.EnrichrResultTabletoCircosPlotMenu = uimenu(app.PlotsMenu);
            app.EnrichrResultTabletoCircosPlotMenu.Text = 'Enrichr Result Table to Circos Plot...';
            app.CellStateRadarPlotMenu = uimenu(app.PlotsMenu);
            app.CellStateRadarPlotMenu.Separator = 'on';
            app.CellStateRadarPlotMenu.Text = 'Cell State Radar Plot...';
            app.CellkNNNetworkMenu = uimenu(app.PlotsMenu);
            app.CellkNNNetworkMenu.Text = 'Cell kNN Network...';
            app.CellTrajectoryMenu = uimenu(app.PlotsMenu);
            app.CellTrajectoryMenu.Text = 'Cell Trajectory...';
            app.NextMarkerTypeMenu = uimenu(app.PlotsMenu);
            app.NextMarkerTypeMenu.Separator = 'on';
            app.NextMarkerTypeMenu.Text = 'Next Marker Type';
            app.NextColormapMenu = uimenu(app.PlotsMenu);
            app.NextColormapMenu.Text = 'Next Colormap';
            app.CleantSNEUMAPPHATEPlotMenu = uimenu(app.PlotsMenu);
            app.CleantSNEUMAPPHATEPlotMenu.Separator = 'on';
            app.CleantSNEUMAPPHATEPlotMenu.Text = 'Clean tSNE/UMAP/PHATE Plot';
            app.AnnotateMenu = uimenu(app.UIFigure);
            app.AnnotateMenu.Text = 'Annotate';
            app.AnnotateCellTypesUsingPanglaoDBMarkerGenesMenu = uimenu(app.AnnotateMenu);
            app.AnnotateCellTypesUsingPanglaoDBMarkerGenesMenu.Text = 'Annotate Cell Types Using PanglaoDB Marker Genes';
            app.AnnotateCellTypesUsingCustomizedMarkerGenesMenu = uimenu(app.AnnotateMenu);
            app.AnnotateCellTypesUsingCustomizedMarkerGenesMenu.Text = 'Annotate Cell Types Using Customized Marker Genes...';
            app.ImportCellAnnotationfromSCEinWorkspaceMenu = uimenu(app.AnnotateMenu);
            app.ImportCellAnnotationfromSCEinWorkspaceMenu.Separator = 'on';
            app.ImportCellAnnotationfromSCEinWorkspaceMenu.Text = 'Import Cell Annotation from SCE in Workspace...';
            app.ImportCellAnnotationfromSCEDataFileMenu = uimenu(app.AnnotateMenu);
            app.ImportCellAnnotationfromSCEDataFileMenu.Text = 'Import Cell Annotation from SCE Data File...';
            app.AnnotateCellTypesforBrushedCellsMenu = uimenu(app.AnnotateMenu);
            app.AnnotateCellTypesforBrushedCellsMenu.Separator = 'on';
            app.AnnotateCellTypesforBrushedCellsMenu.Text = 'Annotate Cell Types for Brushed Cells';
            app.FindMarkerGenesforBrushedCellsMenu = uimenu(app.AnnotateMenu);
            app.FindMarkerGenesforBrushedCellsMenu.Text = 'Find Marker Genes for Brushed Cells';
            app.MakeMarkerGeneHeatmapMenu = uimenu(app.AnnotateMenu);
            app.MakeMarkerGeneHeatmapMenu.Text = 'Make Marker Gene Heatmap';
            app.EstimateCellCyclePhaseMenu = uimenu(app.AnnotateMenu);
            app.EstimateCellCyclePhaseMenu.Separator = 'on';
            app.EstimateCellCyclePhaseMenu.Text = 'Estimate Cell Cycle Phase...';
            app.EstimateDifferentiationPotencyMenu = uimenu(app.AnnotateMenu);
            app.EstimateDifferentiationPotencyMenu.Text = 'Estimate Differentiation Potency...';
            app.EstimateStemnessMenu = uimenu(app.AnnotateMenu);
            app.EstimateStemnessMenu.Text = 'Estimate Stemness...';
            app.EstimateDissociationGeneRatioMenu = uimenu(app.AnnotateMenu);
            app.EstimateDissociationGeneRatioMenu.Text = 'Estimate Dissociation Gene Ratio...';
            app.SingleClickSolutionfromRawDatatoAnnotationMenu = uimenu(app.AnnotateMenu);
            app.SingleClickSolutionfromRawDatatoAnnotationMenu.Separator = 'on';
            app.SingleClickSolutionfromRawDatatoAnnotationMenu.Text = 'Single Click Solution (from Raw Data to Annotation)...';
            app.AnalyzeMenu = uimenu(app.UIFigure);
            app.AnalyzeMenu.Text = 'Analyze';
            app.GeneExpressionStatisticsMenu = uimenu(app.AnalyzeMenu);
            app.GeneExpressionStatisticsMenu.Text = 'Gene Expression Statistics...';
            app.GeneVariabilityHVGFunctionAnalysisMenu = uimenu(app.AnalyzeMenu);
            app.GeneVariabilityHVGFunctionAnalysisMenu.Text = 'Gene Variability (HVG Function) Analysis...';
            app.GeneProgramCellScoreAnalysisMenu = uimenu(app.AnalyzeMenu);
            app.GeneProgramCellScoreAnalysisMenu.Text = 'Gene Program (Cell Score) Analysis...';
            app.DifferentialExpressionDEAnalysisMenu = uimenu(app.AnalyzeMenu);
            app.DifferentialExpressionDEAnalysisMenu.Separator = 'on';
            app.DifferentialExpressionDEAnalysisMenu.Accelerator = 'd';
            app.DifferentialExpressionDEAnalysisMenu.Text = 'Differential Expression (DE) Analysis...';
            app.DifferentialVariabilityDVAnalysisMenu = uimenu(app.AnalyzeMenu);
            app.DifferentialVariabilityDVAnalysisMenu.Accelerator = 'v';
            app.DifferentialVariabilityDVAnalysisMenu.Text = 'Differential Variability (DV) Analysis...';
            app.DifferentialProgramDPAnalysisMenu = uimenu(app.AnalyzeMenu);
            app.DifferentialProgramDPAnalysisMenu.Accelerator = 'p';
            app.DifferentialProgramDPAnalysisMenu.Text = 'Differential Program (DP) Analysis...';
            app.DEAnalysisinCellTypeBatchModeMenu = uimenu(app.AnalyzeMenu);
            app.DEAnalysisinCellTypeBatchModeMenu.Separator = 'on';
            app.DEAnalysisinCellTypeBatchModeMenu.Text = 'DE Analysis in Cell Type Batch Mode...';
            app.DVAnalysisinCellTypeBatchModeMenu = uimenu(app.AnalyzeMenu);
            app.DVAnalysisinCellTypeBatchModeMenu.Text = 'DV Analysis in Cell Type Batch Mode...';
            app.DPAnalysisinCellTypeBatchModeMenu = uimenu(app.AnalyzeMenu);
            app.DPAnalysisinCellTypeBatchModeMenu.Text = 'DP Analysis in Cell Type Batch Mode...';
            app.AllDEDVDPAnalysisinCellTypeBatchModeMenu = uimenu(app.AnalyzeMenu);
            app.AllDEDVDPAnalysisinCellTypeBatchModeMenu.Separator = 'on';
            app.AllDEDVDPAnalysisinCellTypeBatchModeMenu.Text = 'All (DE, DV, DP) Analysis in Cell Type Batch Mode...';
            app.LASSOFeatureSelectionMenu = uimenu(app.AnalyzeMenu);
            app.LASSOFeatureSelectionMenu.Separator = 'on';
            app.LASSOFeatureSelectionMenu.Text = 'LASSO Feature Selection...';
            app.QUBOFeatureSelectionMenu = uimenu(app.AnalyzeMenu);
            app.QUBOFeatureSelectionMenu.Text = 'QUBO Feature Selection...';
            app.NetworkMenu = uimenu(app.UIFigure);
            app.NetworkMenu.Text = 'Network';
            app.RemoveLessInformativeGenestoReduceGeneSpaceMenu = uimenu(app.NetworkMenu);
            app.RemoveLessInformativeGenestoReduceGeneSpaceMenu.Text = 'Remove Less Informative Genes to Reduce Gene Space...';
            app.PlotGRNfromEdgeGenePairListMenu = uimenu(app.NetworkMenu);
            app.PlotGRNfromEdgeGenePairListMenu.MenuSelectedFcn = createCallbackFcn(app, @PlotGRNfromEdgeGenePairListMenuSelected, true);
            app.PlotGRNfromEdgeGenePairListMenu.Text = 'Plot GRN from Edge (Gene Pair) List...';
            app.BuildGRNwithSelectedGenesMenu = uimenu(app.NetworkMenu);
            app.BuildGRNwithSelectedGenesMenu.Separator = 'on';
            app.BuildGRNwithSelectedGenesMenu.Text = 'Build GRN with Selected Genes...';
            app.BuildCompareGRNsMenu = uimenu(app.NetworkMenu);
            app.BuildCompareGRNsMenu.Text = 'Build & Compare GRNs...';
            app.ConstructGRNwithAllGenesscTenifoldNetPMID33336197Menu = uimenu(app.NetworkMenu);
            app.ConstructGRNwithAllGenesscTenifoldNetPMID33336197Menu.Separator = 'on';
            app.ConstructGRNwithAllGenesscTenifoldNetPMID33336197Menu.Text = 'Construct GRN with All Genes - scTenifoldNet [PMID:33336197]🐢..';
            app.ConstructCompareGRNsscTenifoldNetPMID33336197Menu = uimenu(app.NetworkMenu);
            app.ConstructCompareGRNsscTenifoldNetPMID33336197Menu.Text = 'Construct & Compare GRNs - scTenifoldNet [PMID:33336197] 🐢...';
            app.VirtualGeneKnockoutscTenifoldKnkPMID35510185Menu = uimenu(app.NetworkMenu);
            app.VirtualGeneKnockoutscTenifoldKnkPMID35510185Menu.Separator = 'on';
            app.VirtualGeneKnockoutscTenifoldKnkPMID35510185Menu.Text = 'Virtual Gene Knockout - scTenifoldKnk [PMID:35510185] 🐢...';
            app.VirtualGeneKnockoutGenKIPMID37246643Menu = uimenu(app.NetworkMenu);
            app.VirtualGeneKnockoutGenKIPMID37246643Menu.Text = 'Virtual Gene Knockout (GenKI/ðŸ��) [PMID:37246643] ...';
            app.OneSampleCellCellCommunicationscTenifoldXctPMID36787742Menu = uimenu(app.NetworkMenu);
            app.OneSampleCellCellCommunicationscTenifoldXctPMID36787742Menu.Separator = 'on';
            app.OneSampleCellCellCommunicationscTenifoldXctPMID36787742Menu.Text = 'One-Sample Cell-Cell Communication (scTenifoldXct/🐍) [PMID:36787742]...';
            app.TwoSampleCellCellCommunicationscTenifoldXct2PMID36787742Menu = uimenu(app.NetworkMenu);
            app.TwoSampleCellCellCommunicationscTenifoldXct2PMID36787742Menu.Text = 'Two-Sample Cell-Cell Communication (scTenifoldXct2/🐍) [PMID:36787742]...';
            app.VirtualCellCellCommunicationKnockoutscTenifoldCkoExperimentalMenu = uimenu(app.NetworkMenu);
            app.VirtualCellCellCommunicationKnockoutscTenifoldCkoExperimentalMenu.Text = 'Virtual Cell-Cell Communication Knockout - scTenifoldCko/🐍) [Experimental] 🐢 ...';
            app.ExternalMenu = uimenu(app.UIFigure);
            app.ExternalMenu.Text = 'External';
            app.SetRandomSeedMenu = uimenu(app.ExternalMenu);
            app.SetRandomSeedMenu.Text = 'Set Random Seed...';
            app.SetWorkingFolderMenu = uimenu(app.ExternalMenu);
            app.SetWorkingFolderMenu.MenuSelectedFcn = createCallbackFcn(app, @SetWorkingFolderMenuSelected, true);
            app.SetWorkingFolderMenu.Text = 'Set Working Folder...';
            app.EnrichrAnalysisMenu = uimenu(app.ExternalMenu);
            app.EnrichrAnalysisMenu.MenuSelectedFcn = createCallbackFcn(app, @EnrichrAnalysisMenuSelected, true);
            app.EnrichrAnalysisMenu.Separator = 'on';
            app.EnrichrAnalysisMenu.Text = '🌐 - Enrichr Analysis...';
            app.SetUpLargeLanguageModelMenu = uimenu(app.ExternalMenu);
            app.SetUpLargeLanguageModelMenu.MenuSelectedFcn = createCallbackFcn(app, @SetUpLargeLanguageModelMenuSelected, true);
            app.SetUpLargeLanguageModelMenu.Separator = 'on';
            app.SetUpLargeLanguageModelMenu.Text = '🤖 - Set Up Large Language Model...';
            app.SetUpScimilarityModelMenu = uimenu(app.ExternalMenu);
            app.SetUpScimilarityModelMenu.Text = '🔢 - Set Up Scimilarity Model...';
            app.SetUpQuantumComputingSupportPackageMenu = uimenu(app.ExternalMenu);
            app.SetUpQuantumComputingSupportPackageMenu.Text = '⚛ - Set Up Quantum Computing Support Package...';
            app.SetUpREnvironmentMenu = uimenu(app.ExternalMenu);
            app.SetUpREnvironmentMenu.Separator = 'on';
            app.SetUpREnvironmentMenu.Text = 'Set Up R (ℝ) Environment';
            app.SetUpPythonEnvironmentMenu = uimenu(app.ExternalMenu);
            app.SetUpPythonEnvironmentMenu.Text = 'Set Up Python (🐍) Environment';
            app.UMAPtSNEEmbeddingUsingSeuratPMID25867923Menu = uimenu(app.ExternalMenu);
            app.UMAPtSNEEmbeddingUsingSeuratPMID25867923Menu.Separator = 'on';
            app.UMAPtSNEEmbeddingUsingSeuratPMID25867923Menu.Text = 'ℝ - UMAP/tSNE Embedding Using Seurat [PMID:25867923]...';
            app.SCTransformv2RegularizationUsingSeuratPMID35042561Menu = uimenu(app.ExternalMenu);
            app.SCTransformv2RegularizationUsingSeuratPMID35042561Menu.Text = 'ℝ - SCTransform v2 Regularization Using Seurat [PMID:35042561]...';
            app.PseudotimeAnalysisMonocle3PMID28825705Menu = uimenu(app.ExternalMenu);
            app.PseudotimeAnalysisMonocle3PMID28825705Menu.Text = 'ℝ - Pseudotime Analysis (Monocle3) [PMID:28825705]...';
            app.AneuploidDiploidAnalysiscopykatPMID33462507Menu = uimenu(app.ExternalMenu);
            app.AneuploidDiploidAnalysiscopykatPMID33462507Menu.Text = 'ℝ - Aneuploid/Diploid Analysis (copykat) [PMID:33462507]...';
            app.VariationalAneuploidyAnalysisSCEVANPMID36841879Menu = uimenu(app.ExternalMenu);
            app.VariationalAneuploidyAnalysisSCEVANPMID36841879Menu.Text = 'ℝ - Variational Aneuploidy Analysis (SCEVAN) [PMID:36841879]...';
            app.DetectAmbientRNAContaminationDecontXPMID32138770Menu = uimenu(app.ExternalMenu);
            app.DetectAmbientRNAContaminationDecontXPMID32138770Menu.Text = 'ℝ - Detect Ambient RNA Contamination (DecontX) [PMID:32138770]...';
            app.MementoDEDVAnalysisPMID39454576Menu = uimenu(app.ExternalMenu);
            app.MementoDEDVAnalysisPMID39454576Menu.Separator = 'on';
            app.MementoDEDVAnalysisPMID39454576Menu.Text = '🐍 - Memento DE/DV Analysis [PMID:39454576]...';
            app.DetectDoubletsScrubletPMID30954476Menu = uimenu(app.ExternalMenu);
            app.DetectDoubletsScrubletPMID30954476Menu.Text = '🐍 - Detect Doublets (Scrublet) [PMID:30954476]...';
            app.BatchIntegrationHarmonyPMID31740819Menu = uimenu(app.ExternalMenu);
            app.BatchIntegrationHarmonyPMID31740819Menu.Text = '🐍 - Batch Integration (Harmony) [PMID:31740819]...';
            app.MELDPerturbationScoreMELDPMID33558698Menu = uimenu(app.ExternalMenu);
            app.MELDPerturbationScoreMELDPMID33558698Menu.Text = '🐍 - MELD Perturbation Score (MELD) [PMID:33558698]...';
            app.AnnotateCellTypesScimilarityPMID39566551Menu = uimenu(app.ExternalMenu);
            app.AnnotateCellTypesScimilarityPMID39566551Menu.Text = '🐍 - Annotate Cell Types (Scimilarity 🔢) [PMID:39566551]...';
            app.AnnotateCellTypesPanHumanAzimuthsatijalaborgMenu = uimenu(app.ExternalMenu);
            app.AnnotateCellTypesPanHumanAzimuthsatijalaborgMenu.Text = '🐍 - Annotate Cell Types (Pan-Human Azimuth) [satijalab.org]...';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.OnlineDocumentationMenu = uimenu(app.HelpMenu);
            app.OnlineDocumentationMenu.MenuSelectedFcn = createCallbackFcn(app, @OnlineDocumentationMenuSelected, true);
            app.OnlineDocumentationMenu.Text = 'Online Documentation...';
            app.ShortcutsUserGuideMenu = uimenu(app.HelpMenu);
            app.ShortcutsUserGuideMenu.MenuSelectedFcn = createCallbackFcn(app, @ShortcutsUserGuideMenuSelected, true);
            app.ShortcutsUserGuideMenu.Text = 'Shortcuts User Guide...';
            app.ViewscGEAToolboxonFileExchangeMenu = uimenu(app.HelpMenu);
            app.ViewscGEAToolboxonFileExchangeMenu.MenuSelectedFcn = createCallbackFcn(app, @ViewscGEAToolboxonFileExchangeMenuSelected, true);
            app.ViewscGEAToolboxonFileExchangeMenu.Separator = 'on';
            app.ViewscGEAToolboxonFileExchangeMenu.Text = 'View scGEAToolbox on File Exchange...';
            app.CitescGEAToolboxPaperMenu = uimenu(app.HelpMenu);
            app.CitescGEAToolboxPaperMenu.MenuSelectedFcn = createCallbackFcn(app, @CitescGEAToolboxPaperMenuSelected, true);
            app.CitescGEAToolboxPaperMenu.Text = 'Cite scGEAToolbox Paper...';
            app.PapersCitingscGEAToolboxMenu = uimenu(app.HelpMenu);
            app.PapersCitingscGEAToolboxMenu.MenuSelectedFcn = createCallbackFcn(app, @PapersCitingscGEAToolboxMenuSelected, true);
            app.PapersCitingscGEAToolboxMenu.Text = 'Papers Citing scGEAToolbox...';
            app.VisitSCGEATOOLStandaloneWebsiteMenu = uimenu(app.HelpMenu);
            app.VisitSCGEATOOLStandaloneWebsiteMenu.MenuSelectedFcn = createCallbackFcn(app, @VisitSCGEATOOLStandaloneWebsiteMenuSelected, true);
            app.VisitSCGEATOOLStandaloneWebsiteMenu.Separator = 'on';
            app.VisitSCGEATOOLStandaloneWebsiteMenu.Text = 'Visit SCGEATOOL-Standalone Website...';
            app.RunSCGEATOOLinMATLABOnlineMenu = uimenu(app.HelpMenu);
            app.RunSCGEATOOLinMATLABOnlineMenu.MenuSelectedFcn = createCallbackFcn(app, @RunSCGEATOOLinMATLABOnlineMenuSelected, true);
            app.RunSCGEATOOLinMATLABOnlineMenu.Text = 'Run SCGEATOOL in MATLAB Online...';
            app.InstallscgeatoolAppMenu = uimenu(app.HelpMenu);
            app.InstallscgeatoolAppMenu.Text = 'Install scgeatoolApp...';
            app.CheckforUpdatesMenu = uimenu(app.HelpMenu);
            app.CheckforUpdatesMenu.Separator = 'on';
            app.CheckforUpdatesMenu.Text = 'Check for Updates...';
            app.AboutSCGEATOOLMenu = uimenu(app.HelpMenu);
            app.AboutSCGEATOOLMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutSCGEATOOLMenuSelected, true);
            app.AboutSCGEATOOLMenu.Separator = 'on';
            app.AboutSCGEATOOLMenu.Text = 'About SCGEATOOL';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.PushTool_ImportData = uipushtool(app.Toolbar);
            app.PushTool_ImportData.Tooltip = {'Import Data...'};
            app.PushTool_ImportData.ClickedCallback = createCallbackFcn(app, @PushTool_ImportDataClicked, true);
            app.PushTool_ImportData.Icon = fullfile(pathToMLAPP, 'assets', 'Images', 'box3d-center.jpg');
            app.PushTool_MultigroupingView = uipushtool(app.Toolbar);
            app.PushTool_MultigroupingView.Tooltip = {'Multi-grouping View...'};
            app.PushTool_MultigroupingView.ClickedCallback = createCallbackFcn(app, @PushTool_MultigroupingViewClicked, true);
            app.PushTool_MultigroupingView.Icon = fullfile(pathToMLAPP, 'assets', 'Images', 'visibility_18dp_000000_FILL0_wght400_GRAD0_opsz20.jpg');
            app.PushTool_CrossTabulation = uipushtool(app.Toolbar);
            app.PushTool_CrossTabulation.Tooltip = {'Cross tabulation...'};
            app.PushTool_CrossTabulation.ClickedCallback = createCallbackFcn(app, @PushTool_CrossTabulationClicked, true);
            app.PushTool_CrossTabulation.Icon = fullfile(pathToMLAPP, 'assets', 'Images', 'full_stacked_bar_chart_16dp_000000_FILL0_wght400_GRAD0_opsz20.jpg');
            app.PushTool35 = uipushtool(app.Toolbar);
            app.PushTool35.Icon = fullfile(pathToMLAPP, 'assets', 'Images', 'dataset_16dp_000000_FILL0_wght400_GRAD0_opsz20.jpg');
            app.PushTool_GeneDotPlot = uipushtool(app.Toolbar);
            app.PushTool_GeneDotPlot.Tooltip = {'Gene Expression Dot Plot...'};
            app.PushTool_GeneDotPlot.ClickedCallback = createCallbackFcn(app, @PushTool_GeneDotPlotClicked, true);
            app.PushTool_GeneDotPlot.Icon = fullfile(pathToMLAPP, 'assets', 'Images', 'icon-mat-blur-linear-10.gif');
            app.PushTool_GeneDotPlot.Separator = 'on';
            app.PushTool_GeneHeatmap = uipushtool(app.Toolbar);
            app.PushTool_GeneHeatmap.Tooltip = {'Gene Expression Heatmap...'};
            app.PushTool_GeneHeatmap.ClickedCallback = createCallbackFcn(app, @PushTool_GeneHeatmapClicked, true);
            app.PushTool_GeneHeatmap.Icon = fullfile(pathToMLAPP, 'assets', 'Images', 'icon-mat-apps-20.gif');
            app.PushTool_ScatterStemPlot = uipushtool(app.Toolbar);
            app.PushTool_ScatterStemPlot.Tooltip = {'Gene Expression/Cell State Stem Plot...'};
            app.PushTool_ScatterStemPlot.ClickedCallback = createCallbackFcn(app, @PushTool_ScatterStemPlotClicked, true);
            app.PushTool_ScatterStemPlot.Icon = fullfile(pathToMLAPP, 'assets', 'Images', 'signal_cellular_pause_16dp_000000_FILL0_wght400_GRAD0_opsz20.jpg');
            app.PushTool19 = uipushtool(app.Toolbar);
            app.PushTool19.Icon = fullfile(pathToMLAPP, 'assets', 'Images', 'violinplot.gif');
            app.PushTool20 = uipushtool(app.Toolbar);
            app.PushTool20.Icon = fullfile(pathToMLAPP, 'assets', 'Images', 'icon-mat-blur-off-10a.gif');
            app.PushTool_CompareCellScoreBtwCls = uipushtool(app.Toolbar);
            app.PushTool_CompareCellScoreBtwCls.Tooltip = {'Cell score analysis--obtaining gene signature score for each cell'};
            app.PushTool_CompareCellScoreBtwCls.ClickedCallback = createCallbackFcn(app, @PushTool_CompareCellScoreBtwClsClicked, true);
            app.PushTool_CompareCellScoreBtwCls.Icon = fullfile(pathToMLAPP, 'assets', 'Images', 'barcode_scanner_16dp_000000_FILL0_wght400_GRAD0_opsz20.jpg');
            app.PushTool_CompareCellScoreBtwCls.Separator = 'on';
            app.PushTool_GetCellSignatureMatrix = uipushtool(app.Toolbar);
            app.PushTool_GetCellSignatureMatrix.Tooltip = {'Cell state analysis--obtaining multiple gene signature scores to reveal functional state of cells'};
            app.PushTool_GetCellSignatureMatrix.ClickedCallback = createCallbackFcn(app, @PushTool_GetCellSignatureMatrixClicked, true);
            app.PushTool_GetCellSignatureMatrix.Icon = fullfile(pathToMLAPP, 'assets', 'Images', 'hexagon_16dp_000000_FILL0_wght400_GRAD0_opsz20.jpg');
            app.PushTool_DEGene2Groups = uipushtool(app.Toolbar);
            app.PushTool_DEGene2Groups.Tooltip = {'Differential expression (DE) analysis'};
            app.PushTool_DEGene2Groups.ClickedCallback = createCallbackFcn(app, @PushTool_DEGene2GroupsClicked, true);
            app.PushTool_DEGene2Groups.Icon = fullfile(pathToMLAPP, 'assets', 'Images', 'graphic_eq_16dp_000000_FILL0_wght400_GRAD0_opsz20.jpg');
            app.PushTool_DVGene2Groups = uipushtool(app.Toolbar);
            app.PushTool_DVGene2Groups.Tooltip = {'Differential variability (DV) analysis'};
            app.PushTool_DVGene2Groups.ClickedCallback = createCallbackFcn(app, @PushTool_DVGene2GroupsClicked, true);
            app.PushTool_DVGene2Groups.Icon = fullfile(pathToMLAPP, 'assets', 'Images', 'edit_audio_16dp_000000_FILL0_wght400_GRAD0_opsz20.jpg');
            app.PushTool_DPGene2Groups = uipushtool(app.Toolbar);
            app.PushTool_DPGene2Groups.Tooltip = {'Differential program (DP) analysis'};
            app.PushTool_DPGene2Groups.ClickedCallback = createCallbackFcn(app, @PushTool_DPGene2GroupsClicked, true);
            app.PushTool_DPGene2Groups.Icon = fullfile(pathToMLAPP, 'assets', 'Images', 'data_exploration_16dp_000000_FILL0_wght400_GRAD0_opsz20.jpg');
            app.PushTool_BuildGeneNetwork = uipushtool(app.Toolbar);
            app.PushTool_BuildGeneNetwork.Tooltip = {'Build gene regulatory network'};
            app.PushTool_BuildGeneNetwork.ClickedCallback = createCallbackFcn(app, @PushTool_BuildGeneNetworkClicked, true);
            app.PushTool_BuildGeneNetwork.Icon = fullfile(pathToMLAPP, 'assets', 'Images', 'graph_3_18dp_000000_FILL0_wght400_GRAD0_opsz20.jpg');
            app.PushTool_BuildGeneNetwork.Separator = 'on';
            app.PushTool_CompareGeneNetwork = uipushtool(app.Toolbar);
            app.PushTool_CompareGeneNetwork.Tooltip = {'Compare two scGRNs'};
            app.PushTool_CompareGeneNetwork.ClickedCallback = createCallbackFcn(app, @PushTool_CompareGeneNetworkClicked, true);
            app.PushTool_CompareGeneNetwork.Icon = fullfile(pathToMLAPP, 'assets', 'Images', 'graph_5_16dp_000000_FILL0_wght400_GRAD0_opsz20.jpg');
            app.PushTool_SaveFigToPowerPoint = uipushtool(app.Toolbar);
            app.PushTool_SaveFigToPowerPoint.ClickedCallback = createCallbackFcn(app, @PushTool_SaveFigToPowerPointClicked, true);
            app.PushTool_SaveFigToPowerPoint.Icon = fullfile(pathToMLAPP, 'assets', 'Images', 'presentation.jpg');
            app.PushTool_SaveFigToPowerPoint.Separator = 'on';
            app.PushTool_MakeVideoSnapshot = uipushtool(app.Toolbar);
            app.PushTool_MakeVideoSnapshot.Tooltip = {'Make Video Snapshot...'};
            app.PushTool_MakeVideoSnapshot.ClickedCallback = createCallbackFcn(app, @PushTool_MakeVideoSnapshotClicked, true);
            app.PushTool_MakeVideoSnapshot.Icon = fullfile(pathToMLAPP, 'assets', 'Images', 'camera.jpg');
            app.ToggleTool_ShowColorbar = uitoggletool(app.Toolbar);
            app.ToggleTool_ShowColorbar.Icon = fullfile(pathToMLAPP, 'assets', 'Images', 'colorbarcdata.png');
            app.ToggleTool_ShowColorbar.OffCallback = createCallbackFcn(app, @ToggleTool_ShowColorbarOff, true);
            app.ToggleTool_ShowColorbar.OnCallback = createCallbackFcn(app, @ToggleTool_ShowColorbarOn, true);
            app.PushTool_ResizePlotWindow = uipushtool(app.Toolbar);
            app.PushTool_ResizePlotWindow.ClickedCallback = createCallbackFcn(app, @PushTool_ResizePlotWindowClicked, true);
            app.PushTool_ResizePlotWindow.Icon = fullfile(pathToMLAPP, 'assets', 'Images', 'scale-frame-reduce.jpg');
            app.Toolbar2 = uitoolbar(app.UIFigure);
            app.PushTool_ShowGeneExpr = uipushtool(app.Toolbar2);
            app.PushTool_ShowGeneExpr.ClickedCallback = createCallbackFcn(app, @PushTool_ShowGeneExprClicked, true);
            app.PushTool_ShowGeneExpr.Icon = fullfile(pathToMLAPP, 'assets', 'Images', 'google-docs.jpg');
            app.PushTool_ShowCellStates = uipushtool(app.Toolbar2);
            app.PushTool_ShowCellStates.Tooltip = {'Show Cell States...'};
            app.PushTool_ShowCellStates.ClickedCallback = createCallbackFcn(app, @PushTool_ShowCellStatesClicked, true);
            app.PushTool_ShowCellStates.Icon = fullfile(pathToMLAPP, 'assets', 'Images', 'bookmark-book.jpg');
            app.PushTool_FilterGenesCells = uipushtool(app.Toolbar2);
            app.PushTool_FilterGenesCells.Tooltip = {'Filter genes & cells...'};
            app.PushTool_FilterGenesCells.ClickedCallback = createCallbackFcn(app, @PushTool_FilterGenesCellsClicked, true);
            app.PushTool_FilterGenesCells.Icon = fullfile(pathToMLAPP, 'assets', 'Images', 'filter-alt.jpg');
            app.PushTool_LabelCellGroups = uipushtool(app.Toolbar2);
            app.PushTool_LabelCellGroups.Tooltip = {'Label Cell Groups...'};
            app.PushTool_LabelCellGroups.ClickedCallback = createCallbackFcn(app, @PushTool_LabelCellGroupsClicked, true);
            app.PushTool_LabelCellGroups.Icon = fullfile(pathToMLAPP, 'assets', 'Images', 'label.jpg');
            app.PushTool_LabelCellGroups.Separator = 'on';
            app.PushTool_AddBrushedToNewGroup = uipushtool(app.Toolbar2);
            app.PushTool_AddBrushedToNewGroup.Tooltip = {'Add brushed cells to a new group'};
            app.PushTool_AddBrushedToNewGroup.ClickedCallback = createCallbackFcn(app, @PushTool_AddBrushedToNewGroupClicked, true);
            app.PushTool_AddBrushedToNewGroup.Icon = fullfile(pathToMLAPP, 'assets', 'Images', 'substract.jpg');
            app.PushTool_MergeBrushedToSameGroup = uipushtool(app.Toolbar2);
            app.PushTool_MergeBrushedToSameGroup.Tooltip = {'Merge Brushed Cells to Same Group'};
            app.PushTool_MergeBrushedToSameGroup.ClickedCallback = createCallbackFcn(app, @PushTool_MergeBrushedToSameGroupClicked, true);
            app.PushTool_MergeBrushedToSameGroup.Icon = fullfile(pathToMLAPP, 'assets', 'Images', 'union.jpg');
            app.PushTool_RenameCellTypeBatchID = uipushtool(app.Toolbar2);
            app.PushTool_RenameCellTypeBatchID.ClickedCallback = createCallbackFcn(app, @PushTool_RenameCellTypeBatchIDClicked, true);
            app.PushTool_RenameCellTypeBatchID.Icon = fullfile(pathToMLAPP, 'assets', 'Images', 'edit.jpg');
            app.PushTool_SingleClickAnnotation = uipushtool(app.Toolbar2);
            app.PushTool_SingleClickAnnotation.Tooltip = {'Single-click cell type annotation'};
            app.PushTool_SingleClickAnnotation.ClickedCallback = createCallbackFcn(app, @PushTool_SingleClickAnnotationClicked, true);
            app.PushTool_SingleClickAnnotation.Icon = fullfile(pathToMLAPP, 'assets', 'Images', 'apple-swift.jpg');
            app.PushTool_ClusterCellsS = uipushtool(app.Toolbar2);
            app.PushTool_ClusterCellsS.Tooltip = {'Clustering using cell embedding (S)'};
            app.PushTool_ClusterCellsS.ClickedCallback = createCallbackFcn(app, @PushTool_ClusterCellsSClicked, true);
            app.PushTool_ClusterCellsS.Icon = fullfile(pathToMLAPP, 'assets', 'Images', 'bubble_chart_16dp_000000_FILL0_wght400_GRAD0_opsz20.jpg');
            app.PushTool_DetermineCellTypeCluster = uipushtool(app.Toolbar2);
            app.PushTool_DetermineCellTypeCluster.Tooltip = {'Assign cell types to groups'};
            app.PushTool_DetermineCellTypeCluster.ClickedCallback = createCallbackFcn(app, @PushTool_DetermineCellTypeClusterClicked, true);
            app.PushTool_DetermineCellTypeCluster.Icon = fullfile(pathToMLAPP, 'assets', 'Images', 'auto_transmission_16dp_000000_FILL0_wght400_GRAD0_opsz20.jpg');
            app.PushTool_Brush4Celltypes = uipushtool(app.Toolbar2);
            app.PushTool_Brush4Celltypes.Tooltip = {'Assign cell type to selected cells'};
            app.PushTool_Brush4Celltypes.ClickedCallback = createCallbackFcn(app, @PushTool_Brush4CelltypesClicked, true);
            app.PushTool_Brush4Celltypes.Icon = fullfile(pathToMLAPP, 'assets', 'Images', 'color-picker.jpg');
            app.PushTool_Brush4Markers = uipushtool(app.Toolbar2);
            app.PushTool_Brush4Markers.Tooltip = {'Find Marker Genes for Brushed Cells'};
            app.PushTool_Brush4Markers.ClickedCallback = createCallbackFcn(app, @PushTool_Brush4MarkersClicked, true);
            app.PushTool_Brush4Markers.Icon = fullfile(pathToMLAPP, 'assets', 'Images', 'medal1st.jpg');
            app.PushTool_FindAllMarkers = uipushtool(app.Toolbar2);
            app.PushTool_FindAllMarkers.Tooltip = {'Make Marker Gene Heatmap'};
            app.PushTool_FindAllMarkers.ClickedCallback = createCallbackFcn(app, @PushTool_FindAllMarkersClicked, true);
            app.PushTool_FindAllMarkers.Icon = fullfile(pathToMLAPP, 'assets', 'Images', 'grain_16dp_000000_FILL0_wght400_GRAD0_opsz20.jpg');
            app.PushTool_ShowClustersPop = uipushtool(app.Toolbar2);
            app.PushTool_ShowClustersPop.Tooltip = {'Show cell clusters/groups individually'};
            app.PushTool_ShowClustersPop.ClickedCallback = createCallbackFcn(app, @PushTool_ShowClustersPopClicked, true);
            app.PushTool_ShowClustersPop.Icon = fullfile(pathToMLAPP, 'assets', 'Images', 'view-grid.jpg');
            app.PushTool_ShowClustersPop.Separator = 'on';
            app.PushTool_SelectCellsByClass = uipushtool(app.Toolbar2);
            app.PushTool_SelectCellsByClass.Tooltip = {'SelectCellsByClass'};
            app.PushTool_SelectCellsByClass.ClickedCallback = createCallbackFcn(app, @PushTool_SelectCellsByClassClicked, true);
            app.PushTool_SelectCellsByClass.Icon = fullfile(pathToMLAPP, 'assets', 'Images', 'checklist_rtl_18dp_000000_FILL0_wght400_GRAD0_opsz20.jpg');
            app.PushTool_DeleteSelectedCells = uipushtool(app.Toolbar2);
            app.PushTool_DeleteSelectedCells.Tooltip = {'Delete brushed/selected cells'};
            app.PushTool_DeleteSelectedCells.ClickedCallback = createCallbackFcn(app, @PushTool_DeleteSelectedCellsClicked, true);
            app.PushTool_DeleteSelectedCells.Icon = fullfile(pathToMLAPP, 'assets', 'Images', 'variable_remove_18dp_000000_FILL0_wght400_GRAD0_opsz20.jpg');
            app.PushTool_SaveX = uipushtool(app.Toolbar2);
            app.PushTool_SaveX.ClickedCallback = createCallbackFcn(app, @PushTool_SaveXClicked, true);
            app.PushTool_SaveX.Icon = fullfile(pathToMLAPP, 'assets', 'Images', 'floppy-disk.jpg');
            app.PushTool_EmbedCells = uipushtool(app.Toolbar2);
            app.PushTool_EmbedCells.Tooltip = {'Embed Cells Using tSNE, UMP, PHATE...'};
            app.PushTool_EmbedCells.ClickedCallback = createCallbackFcn(app, @PushTool_EmbedCellsClicked, true);
            app.PushTool_EmbedCells.Icon = fullfile(pathToMLAPP, 'assets', 'Images', 'cinema-old.jpg');
            app.PushTool_EmbedCells.Separator = 'on';
            app.PushTool_Switch2D3DEmbeddings = uipushtool(app.Toolbar2);
            app.PushTool_Switch2D3DEmbeddings.Tooltip = {'Switch Between 2D/3D Embeddings...'};
            app.PushTool_Switch2D3DEmbeddings.ClickedCallback = createCallbackFcn(app, @PushTool_Switch2D3DEmbeddingsClicked, true);
            app.PushTool_Switch2D3DEmbeddings.Icon = fullfile(pathToMLAPP, 'assets', 'Images', 'perspective-view.jpg');
            app.PushTool_PickMarkerType = uipushtool(app.Toolbar2);
            app.PushTool_PickMarkerType.Tooltip = {'Pick scatter plot marker type'};
            app.PushTool_PickMarkerType.ClickedCallback = createCallbackFcn(app, @PushTool_PickMarkerTypeClicked, true);
            app.PushTool_PickMarkerType.Icon = fullfile(pathToMLAPP, 'assets', 'Images', 'palette.jpg');
            app.PushTool_PIckColormap = uipushtool(app.Toolbar2);
            app.PushTool_PIckColormap.ClickedCallback = createCallbackFcn(app, @PushTool_PIckColormapClicked, true);
            app.PushTool_PIckColormap.Icon = fullfile(pathToMLAPP, 'assets', 'Images', 'color-wheel.jpg');
            app.PushTool_Refresh = uipushtool(app.Toolbar2);
            app.PushTool_Refresh.Tooltip = {'Refresh'};
            app.PushTool_Refresh.ClickedCallback = createCallbackFcn(app, @PushTool_RefreshClicked, true);
            app.PushTool_Refresh.Icon = fullfile(pathToMLAPP, 'assets', 'Images', 'refresh_16dp_000000_FILL0_wght400_GRAD0_opsz20.jpg');
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = scgeatoolApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end